classdef MIMODetDemo_v3a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MIMOUIFigure               matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        ApplicationTab             matlab.ui.container.Tab
        CaseDropDownLabel          matlab.ui.control.Label
        CaseDropDown               matlab.ui.control.DropDown
        mTextAreaLabel             matlab.ui.control.Label
        mTextArea                  matlab.ui.control.TextArea
        KEditFieldLabel            matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        SphereEditFieldLabel       matlab.ui.control.Label
        SphereEditField            matlab.ui.control.NumericEditField
        SICCheckBox                matlab.ui.control.CheckBox
        IterationEditFieldLabel    matlab.ui.control.Label
        IterationEditField         matlab.ui.control.NumericEditField
        RepeatEditFieldLabel       matlab.ui.control.Label
        RepeatEditField            matlab.ui.control.NumericEditField
        NeighbourEditFieldLabel    matlab.ui.control.Label
        NeighbourEditField         matlab.ui.control.NumericEditField
        TabuPeriodEditFieldLabel   matlab.ui.control.Label
        TabuPeriodEditField        matlab.ui.control.NumericEditField
        ReactiveEditFieldLabel     matlab.ui.control.Label
        ReactiveEditField          matlab.ui.control.NumericEditField
        DecodingTab                matlab.ui.container.Tab
        CodingSchemeDropDownLabel  matlab.ui.control.Label
        CodingSchemeDropDown       matlab.ui.control.DropDown
        SimulationTab              matlab.ui.container.Tab
        HoldFigureCheckBox         matlab.ui.control.CheckBox
        LegendButton               matlab.ui.control.Button
        minSNREditFieldLabel       matlab.ui.control.Label
        minSNREditField            matlab.ui.control.NumericEditField
        maxSNREditFieldLabel       matlab.ui.control.Label
        maxSNREditField            matlab.ui.control.NumericEditField
        TXEditFieldLabel           matlab.ui.control.Label
        TXEditField                matlab.ui.control.NumericEditField
        RXEditFieldLabel           matlab.ui.control.Label
        RXEditField                matlab.ui.control.NumericEditField
        ModulationDropDownLabel    matlab.ui.control.Label
        ModulationDropDown         matlab.ui.control.DropDown
        ChannelDropDownLabel       matlab.ui.control.Label
        ChannelDropDown            matlab.ui.control.DropDown
        Corr_typeDropDownLabel     matlab.ui.control.Label
        Corr_typeDropDown          matlab.ui.control.DropDown
        SNRstepEditFieldLabel      matlab.ui.control.Label
        SNRstepEditField           matlab.ui.control.NumericEditField
        SampleScaleEditFieldLabel  matlab.ui.control.Label
        SampleScaleEditField       matlab.ui.control.NumericEditField
        RunButton                  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        FileTab                    matlab.ui.container.Tab
        FigureNameEditFieldLabel   matlab.ui.control.Label
        FigureNameEditField        matlab.ui.control.EditField
        PasswordEditFieldLabel     matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        SaveButton                 matlab.ui.control.Button
    end

    % ELE3001
    % created by yunwu 2017.03.01
    % modified by 
        
    properties (Access = public)
        Case = 'MF';% Description
        Channel = 'Uncorrelated'; % Channle type
        Corr_ind = 0; % channel correlation level index
        Min_SNR = 6; % starting SNR
        snrstep = 2; % SNR step 
        Max_SNR = 26; % end SNR
        mt = 2 % transmit antenna number
        nr = 2 % receive antenna number      
        len = 4096; % channel fram size
        H_corr; % correlated channel response
        index = 2; % modulation index
        m = [1 1]; % m vector for SSFE and BSS-EFE
        K = 1; % K value for KBEST
        frame_number = [100 100 100 100 200 200 200 400 500 500 800]*500; % simulation length
        frame_length = 1; % frame size
        figurehold = 'off'; % figure hold or not
        Figurename = 'MIMO_Det'; % save figure name
        plot_x = []; % saved x value
        plot_y = []; % saved y value
        plot_legend = {}; % saved legend
        samplescale = 1; % scaling sample number
        Sphere = realmax; % the sphere value of GSD
        Real_Arith = 0; % operating in real or complex computation
        With_SIC = 0; % flag of successive interfance cancellation
        Nb = 3; % number of neighbours are considered
        P_init = 900; % 
        iter_max = 300; % maximum number of iteration for TS
        beta = 0.1; % 
        rep_max = 300; % maximum repeated candidate number for TS
        started = 20171030;
        expired = 20181030;
    end
    
    properties (Access = private)        
        Password = 'QUBELE3001';
        SaveEn = false;
    end

    methods (Access = public)
        
        function H=RAND_CH(app)
            H=(randn(app.nr,app.mt,app.len)+randn(app.nr,app.mt,app.len)*sqrt(-1))/sqrt(2);
        end
        
        function H=LTE_TU_CH(app)
            switch(app.Corr_ind)
                case 0
                    R=LOW_CORR(app);
                case 1
                    R=MED_CORR(app);
                case 2
                    R=HIGH_CORR(app);
                otherwise
                    disp('Invalide Scenario');
                    exit(0);
            end 
            TAP = ETU(app);
            H = zeros(app.nr,app.mt,app.len);
            for i=1:app.nr
                for j=1:app.mt
                    H(i,j,:) = fft(TAP(i,j,:),app.len);
                end
            end
            [V,D] = eig(R);
            G=V*sqrt(D);
            vec_r = zeros(1,app.nr*app.mt);
            H_cor = zeros(app.nr,app.mt,app.len);
            for k=1:app.len
                for j=1:app.mt
                    for i=1:app.nr
                        vec_r(i+(j-1)*app.nr)=H(i,j,k);
                    end
                end
                vec_H=G*vec_r';
                for j=1:app.mt
                    for i=1:app.nr
                        H_cor(i,j,k)=vec_H(i+(j-1)*app.nr);
                    end;
                end
            end
        end
        
        function TAP = ETU(app)
            ns = [0 50 120 200 230 500 1600 2300 5000];
            db = [-1 -1 -1 0 0 0 -3 -5 -7];
            pow = 10.^db/10;
            pow_avg = pow/sum(pow);
            amp = sqrt(pow_avg);
            ns_scale = 1+ns/10;
            TAP = zeros(app.nr,app.mt,max(ns_scale));
            for i=1:length(ns_scale)
                TAP(:,:,ns_scale(i)) = amp(i)*(randn(app.nr,app.mt)+randn(app.nr,app.mt)*sqrt(-1))/sqrt(2);
            end 
        end
        
        function R=LOW_CORR(app)
            if app.mt==2 && app.nr ==2
                R = eye(app.mt,app.nr);
            elseif app.mt==4 && app.nr ==4
                R = eye(app.mt,app.nr);
            else
                disp('Unsupported Scenario');
                exit(0);
            end
        end
        
        function R=MED_CORR(app)
            if app.mt==2 && app.nr ==2
                R = [1 0.9 0.3 0.27;
                     0.27 1 0.9 0.3;
                     0.3 0.27 1 0.9;
                     0.27 0.3 0.9 1;];
            elseif app.mt==4 && app.nr ==4
                R = [1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588 0.5270 0.3000 0.2965 0.2862 0.2700;
                     0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588 0.5270 0.3000 0.2965 0.2862;
                     0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588 0.5270 0.3000 0.2965;
                     0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588 0.5270 0.3000;
                     0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588 0.5270;
                     0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787 0.5588;
                     0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855 0.5787;
                     0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872 0.5855;
                     0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347 0.7872;
                     0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645 0.8347;
                     0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747 0.8645;
                     0.5270 0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.8747;
                     0.3000 0.5270 0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999;
                     0.2965 0.3000 0.5270 0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882 0.9541;
                     0.2862 0.2965 0.3000 0.5270 0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1 0.9882;
                     0.2700 0.2862 0.2965 0.3000 0.5270 0.5588 0.5787 0.5855 0.7872 0.8347 0.8645 0.8747 0.8999 0.9541 0.9882 1;];
            else
                disp('Unsupported Scenario');
                exit(0);
            end
        end
        
        function R=HIGH_CORR(app)
            if app.mt==2 && app.nr ==2
                R = [1 0.9 0.9 0.81;
                     0.9 1 0.81 0.9;
                     0.9 0.81 1 0.9;
                     0.81 0.9 0.9 1;];
            elseif app.mt==4 && app.nr ==4
                R = [1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105 0.8587 0.8999 0.8894 0.8587 0.8099;
                     0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105 0.8587 0.8999 0.8894 0.8587;
                     0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105 0.8587 0.8999 0.8894;
                     0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105 0.8587 0.8999;
                     0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105 0.8587;
                     0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430 0.9105;
                     0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541 0.9430;
                     0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894 0.9541;
                     0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430 0.8894;
                     0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767 0.9430;
                     0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882 0.9767;
                     0.8587 0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999 0.9882;
                     0.8999 0.8587 0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541 0.8999;
                     0.8894 0.8999 0.8587 0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882 0.9541;
                     0.8587 0.8894 0.8999 0.8587 0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1 0.9882;
                     0.8099 0.8587 0.8894 0.8999 0.8587 0.9105 0.9430 0.9541 0.8894 0.9430 0.9767 0.9882 0.8999 0.9541 0.9882 1;];
            else
                disp('Unsupported Scenario');
                exit(0);
            end
        end
        
        function x = BitToSymbolStream(~,B,Nbits)
            % MSB first
            
            Lb = length(B);
            if mod(Lb,Nbits)~=0
               error('Bitstream length is incompatible with symbol length');
            end;
            
            Lx = Lb/Nbits;
            
            x = zeros(1,Lx);
            for n = 1:Nbits
               x = x + 2^(Nbits-n)*B(n:Nbits:Lb);
            end
            
        end
        
        function B = SymbolToBitStream(~,X,Nbits)
            % B = SymbolToBitStream(X,Nbits);
            %
            % MSB first
            
            
            Lx = length(X);
            Lb = Lx*Nbits;
            B = zeros(1,Lb);
            
            for n = Nbits:-1:1
               U = floor(X./2^(n-1));
               B(Nbits-n+1:Nbits:Lb)=U;
               X = X - 2^(n-1)*U;
            end
        end
        
        function y = GrayCoding(~,x,Nbits)
            
            y = 0;
            dummy = 0;
            
            for i = Nbits:-1:1
                A = floor(x/2^(i-1));
                if (A==1)    		% i-th bit is set
            	x = x - 2^(i-1);
                end;
                if (A~=dummy)
                   y = y+2^(i-1);
                   dummy = 1;
                else
                   dummy = 0;	
                end
            end
            
        end       
        
        function y = GrayDecoding(~,x,Nbits)            
            
            y = 0;
            dummy = 0;            
            for i = Nbits:-1:1
                A = floor(x/2^(i-1));
                if (A==1)
            	x = x - 2^(i-1);
                end;
                if (A~=dummy)
                   y = y+2^(i-1);
                end;
                dummy = A;       
            end
            
        end
        
        function y = QAM_Slicer(~,Y,Nbits,opt)
               
%             Ly = length(Y);
            Levels = 2^Nbits;
            
            % rescaling
            Scale = [0.7071;
               1.5811;
               3.2404;
               6.5192];
            % Scale = [sqrt(2)/2;
            %    sqrt(10)/2;
            %    sqrt(10);
            %    sqrt(42)];
            Y = Y*Scale(Nbits);
            
            % **** decision slicer ****
            i = round(real(Y)+(Levels-1)/2);
            i(i<0) = 0;
            i(i>(Levels-1)) = Levels-1;
            
            q = round(imag(Y)+(Levels-1)/2);
            q(q<0) = 0;
            q(q>(Levels-1)) = Levels-1;
            
            % restore QAM symbol
            y = i-(Levels-1)/2 + sqrt(-1)*(q-(Levels-1)/2);
            yy = y;
            
            if exist('opt')
               if opt=='renorm'
                   y = yy/Scale(Nbits);
               end;
            end
        end
        
        function y = QAM_Slicer_Real(~,Y,Nbits,opt)
            %
            % Slices a QAM signal with Nbits resolution of both I and Q
            % components. 
            %
            % Input parameters:
            %     Y       complex input (scalar or vector)
            %     Nbits   resolution of real and imag part of Y
            %     opt     opt='norm' sets re-normlaisation to unit variance
            %
            % Output parameters:
            %     y       2*2^Nbit QAM symbol
               
            Ly = length(Y);
            Levels = 2^Nbits;
            
            % rescaling
            Scale = [0.7071;
               1.5811;
               3.2404;
               6.5192];
            % Scale = [sqrt(2)/2;
            %    sqrt(10)/2;
            %    sqrt(10);
            %    sqrt(42)];
            Y = Y*Scale(Nbits);
            
            % **** decision slicer ****
            i = round(real(Y)+(Levels-1)/2);
            i(i<0) = 0;
            i(i>(Levels-1)) = Levels-1;
            
            % q = round(imag(Y)+(Levels-1)/2);
            % q(q<0) = 0;
            % q(q>(Levels-1)) = Levels-1;
            
            % restore QAM symbol
            % y = i-(Levels-1)/2 + sqrt(-1)*(q-(Levels-1)/2);
            y = i-(Levels-1)/2;
            yy = y;
            
            if exist('opt','var')
               if opt=='renorm'
                   y = yy/Scale(Nbits);
               end
            end
        end
        
        function X = QAM_Mod(app,x,Nbits)
            %
            
            Lx = length(x);
            
            i = x(1:2:Lx);
            q = x(2:2:Lx);
            
            for n = 1:Lx/2
               i(n) = GrayCoding(app,i(n),Nbits);
               q(n) = GrayCoding(app,q(n),Nbits);
            end;
            
            Levels = 2^Nbits;
            i = i - (Levels-1)/2;
            q = q - (Levels-1)/2;
            
            X = i + sqrt(-1)*q;
            
            % scaling for uniform bit distribution
            % Scale = 1./[0.7071; 
            %        1.5811;
            %        3.2404;
            %        6.5192];
            Scale = 1./[0.7071; 
                   1.5811;
                   3.2404;
                   sqrt(42)];
            
            X = X*Scale(Nbits);
            
        end
        
        function y = QAM_Demod(app,Y,Nbits)
            
            Ly = length(Y);
            Levels = 2^Nbits;
            Scale = [0.7071;
               1.5811;
               3.2404;
               sqrt(42)];
            Y = Y*Scale(Nbits);        
            % **** decision slicer ****
            i = round(real(Y)+(Levels-1)/2);
            i(i<0) = 0;
            i(i>(Levels-1)) = Levels-1;            
            q = round(imag(Y)+(Levels-1)/2);
            q(q<0) = 0;
            q(q>(Levels-1)) = Levels-1;            
            % **** Gray decoding ****
            for n = 1:Ly
               i(n) = GrayDecoding(app,i(n),Nbits);
               q(n) = GrayDecoding(app,q(n),Nbits);
            end            
            % **** interleaving ****            
            y = zeros(1,2*Ly);
            y(1:2:2*Ly) = i;
            y(2:2:2*Ly) = q;

        end
        
        function  output_mapping= mapping_func(app)
            switch app.index
                case 1
                  output_mapping=[-1 1];
                case 2
                  output_mapping=(1/sqrt(2))*[-1-1i -1+1i 1-1i 1+1i];
                case 3
                  output_mapping=(1/sqrt(6))*[3+1i 1+1i -3+1i -1+1i -3-1i -1-1i 3-1i 1-1i ];
                case 4
                  output_mapping=(1/sqrt(10))*[-3-3*1i -3-1i -3+3*1i -3+1i -1-3*1i -1-1i -1+3*1i -1+1i 3-3*1i 3-1i 3+3*1i 3+1i 1-3*1i 1-1i 1+3*1i 1+1i];
                case 5
                  output_mapping=(1/sqrt(20))*[5+1i 3+1i 1+1i 5+3*1i 3+3*1i 1+3*1i 3+5*1i 1+5*1i -5+1i -3+1i -1+1i -5+3*1i -3+3*1i -1+3*1i -3+5*1i -1+5*1i -5-1i -3-1i -1-1i -5-3*1i -3-3*1i -1-3*1i -3-5*1i -1-5*1i 5-1i 3-1i 1-1i 5-3*1i 3-3*1i 1-3*1i 3-5*1i 1-5*1i];
                case 6
                  output_mapping=(1/sqrt(42))*[1+7*1i 3+7*1i 5+7*1i 7+7*1i 7+1i 7+3*1i 7+5*1i 5+5*1i 5+1i 3+1i 1+1i 5+3*1i 3+3*1i 1+3*1i 3+5*1i 1+5*1i -1+7*1i -3+7*1i -5+7*1i -7+7*1i -7+1i -7+3*1i -7+5*1i -5+5*1i -5+1i -3+1i -1+1i -5+3*1i -3+3*1i -1+3*1i -3+5*1i -1+5*1i  -1-7*1i -3-7*1i -5-7*1i -7-7*1i -7-1i -7-3*1i -7-5*1i -5-5*1i -5-1i -3-1i -1-1i -5-3*1i -3-3*1i -1-3*1i -3-5*1i -1-5*1i 1-7*1i 3-7*1i 5-7*1i 7-7*1i 7-1i 7-3*1i 7-5*1i 5-5*1i 5-1i 3-1i 1-1i 5-3*1i 3-3*1i 1-3*1i 3-5*1i 1-5*1i];
            end 
        end
        
        function ind = ConstellationIndex(~,constellation,sv)
            ind = zeros(1,length(sv));
            order = 1:length(constellation);
            for i=1:length(sv)
                s = sv(i);
                ind(i) = order(constellation==s);
            end
        end
        
        function result = KMinPED(app,PED)
            temp = PED;
            k = app.K;
            result = zeros(1,app.K);
            i = 1;
            while(k>0)
                [ped,ind] = min(temp);
                result(i) = ind;
                k = k - 1;
                i = i + 1;
                temp(temp==ped) = 9e10;
            end
        end
        
        function enum = FastEnum4Qam_Complex(app,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam4Slice(app,x_hat_det_real)+Qam4Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                bool = real(d)>imag(d);
                x_hat_det_enum = x_hat_det * sqrt(2);
                p = [x_hat_det_enum ... 
                    x_hat_det_enum+2*(sign(real(d))*bool+sqrt(-1)*sign(imag(d)*~bool)) ...
                    x_hat_det_enum+2*(sign(real(d))*~bool+sqrt(-1)*sign(imag(d)*bool)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d))) ...
                    x_hat_det_enum-2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum-2*sign(real(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sign(real(d)) ...
                    x_hat_det_enum-2*(sign(real(d))+sqrt(-1)*sign(imag(d)))]/sqrt(2);
                switch(mvalue)
                    case 1
                        enum_tmp = p(1);
                    case 2
                        enum_tmp = p(1:2);
                    case 3
                        enum_tmp = p(1:3);
                    case 4     
                        enum_tmp = p(1:4);
                    case 5
                        enum_tmp = p(1:5);
                    case 6
                        enum_tmp = p(1:6);
                    case 7
                        enum_tmp = p(1:7);
                    case 8
                        enum_tmp = p(1:8);
                    case 9
                        enum_tmp = p(1:9);
                    case 16
                        enum_tmp = [-0.94871-0.94871*sqrt(-1),	-0.31624-0.94871*sqrt(-1),	0.31624-0.94871*sqrt(-1),	0.94871-0.94871*sqrt(-1), ...
                            -0.94871-0.31624*sqrt(-1),	-0.31624-0.31624*sqrt(-1),	0.31624-0.31624*sqrt(-1),	0.94871-0.31624*sqrt(-1), ...
                            -0.94871+0.31624*sqrt(-1),	-0.31624+0.31624*sqrt(-1),	0.31624+0.31624*sqrt(-1),	0.94871+0.31624*sqrt(-1), ...
                            -0.94871+0.94871*sqrt(-1),	-0.31624+0.94871*sqrt(-1),	0.31624+0.94871*sqrt(-1),	0.94871+0.94871*sqrt(-1)];
                    otherwise
                        disp('no larger than 4 for 16QAM');
                end
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
            
        end
        
        function enum = FastEnum16Qam_Complex(app,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam16Slice(app,x_hat_det_real)+Qam16Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                bool = real(d)>imag(d);
                x_hat_det_enum = x_hat_det * sqrt(10);
                p = [x_hat_det_enum ... 
                    x_hat_det_enum+2*(sign(real(d))*bool+sqrt(-1)*sign(imag(d)*~bool)) ...
                    x_hat_det_enum+2*(sign(real(d))*~bool+sqrt(-1)*sign(imag(d)*bool)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d))) ...
                    x_hat_det_enum-2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum-2*sign(real(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sign(real(d)) ...
                    x_hat_det_enum-2*(sign(real(d))+sqrt(-1)*sign(imag(d)))]/sqrt(10);
                switch(mvalue)
                    case 1
                        enum_tmp = p(1);
                    case 2
                        enum_tmp = p(1:2);
                    case 3
                        enum_tmp = p(1:3);
                    case 4     
                        enum_tmp = p(1:4);
                    case 5
                        enum_tmp = p(1:5);
                    case 6
                        enum_tmp = p(1:6);
                    case 7
                        enum_tmp = p(1:7);
                    case 8
                        enum_tmp = p(1:8);
                    case 9
                        enum_tmp = p(1:9);
                    case 16
                        enum_tmp = [-0.94871-0.94871*sqrt(-1),	-0.31624-0.94871*sqrt(-1),	0.31624-0.94871*sqrt(-1),	0.94871-0.94871*sqrt(-1), ...
                            -0.94871-0.31624*sqrt(-1),	-0.31624-0.31624*sqrt(-1),	0.31624-0.31624*sqrt(-1),	0.94871-0.31624*sqrt(-1), ...
                            -0.94871+0.31624*sqrt(-1),	-0.31624+0.31624*sqrt(-1),	0.31624+0.31624*sqrt(-1),	0.94871+0.31624*sqrt(-1), ...
                            -0.94871+0.94871*sqrt(-1),	-0.31624+0.94871*sqrt(-1),	0.31624+0.94871*sqrt(-1),	0.94871+0.94871*sqrt(-1)];
                    otherwise
                        disp('no larger than 4 for 16QAM');
                end
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
            
        end
        
        function enum = FastEnum64Qam_Complex(app,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam64Slice(app,x_hat_det_real)+Qam64Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                bool = real(d)>imag(d);
                x_hat_det_enum = x_hat_det * sqrt(42);
                p = [x_hat_det_enum ... 
                    x_hat_det_enum+2*(sign(real(d))*bool+sqrt(-1)*sign(imag(d)*~bool)) ...
                    x_hat_det_enum+2*(sign(real(d))*~bool+sqrt(-1)*sign(imag(d)*bool)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d))) ...
                    x_hat_det_enum-2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sqrt(-1)*sign(imag(d)) ...
                    x_hat_det_enum-2*sign(real(d)) ...
                    x_hat_det_enum+2*(sign(real(d))+sqrt(-1)*sign(imag(d)))-2*2*sign(real(d)) ...
                    x_hat_det_enum-2*(sign(real(d))+sqrt(-1)*sign(imag(d)))]/sqrt(42);
                switch(mvalue)
                    case 1
                        enum_tmp = p(1);
                    case 2
                        enum_tmp = p(1:2);
                    case 3
                        enum_tmp = p(1:3);
                    case 4     
                        enum_tmp = p(1:4);
                    case 5
                        enum_tmp = p(1:5);
                    case 6
                        enum_tmp = p(1:6);
                    case 7
                        enum_tmp = p(1:7);
                    case 8
                        enum_tmp = p(1:8);
                    case 9
                        enum_tmp = p(1:9);
                    case 64
                        enum_tmp = [0.1543+1.0801i,0.4629+1.0801i,0.7715+1.0801i,1.0801+1.0801i,... 
                                   1.0801+0.1543i,1.0801+0.4629i,1.0801+0.7715i,0.7715+0.7715i,...
                                   0.7715+0.1543i,0.4629+0.1543i,0.1543+0.1543i,0.7715+0.4629i,...
                                   0.4629+0.4629i,0.1543+0.4629i,0.4629+0.7715i,0.1543+0.7715i,...
                                   -0.1543+1.0801i,-0.4629+1.0801i,-0.7715+1.0801i,-1.0801+1.0801i,...
                                   -1.0801+0.1543i,-1.0801+0.4629i,-1.0801+0.7715i,-0.7715+ .7715i,...
                                   -0.7715+0.1543i,-0.4629+0.1543i,-0.1543+0.1543i,-0.7715+0.4629i,...
                                   -0.4629+0.4629i,-0.1543+0.4629i,-0.4629+0.7715i,-0.1543+0.7715i,...
                                   -0.1543-1.0801i,-0.4629-1.0801i,-0.7715-1.0801i,-1.0801-1.0801i,...
                                   -1.0801-0.1543i,-1.0801-0.4629i,-1.0801-0.7715i,-0.7715-0.7715i,...
                                   -0.7715-0.1543i,-0.4629-0.1543i,-0.1543-0.1543i,-0.7715-0.4629i,...
                                   -0.4629-0.4629i,-0.1543-0.4629i,-0.4629-0.7715i,-0.1543-0.7715i,...
                                   0.1543-1.0801i,0.4629-1.0801i,0.7715-1.0801i,1.0801-1.0801i,...
                                   1.0801-0.1543i,1.0801-0.4629i,1.0801-0.7715i,0.7715-0.7715i,...
                                   0.7715-0.1543i,0.4629-0.1543i,0.1543-0.1543i,0.7715-0.4629i,...
                                   0.4629-0.4629i,0.1543-0.4629i,0.4629-0.7715i,0.1543-0.7715i];
                    otherwise
                        disp('no larger than 4 for 16QAM');
                end
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end        
          
        end
        
        function enum = BoundedFastExtendedEnum4Qam_Complex(app,x_hat_tmp,mvalue,num)
            
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam4Slice(app,x_hat_det_real)+Qam4Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                sgnRd = sign(real(d));
                sgnId = sign(imag(d));
                j = sqrt(-1);
                bool = abs(real(d))>abs(imag(d));
                
                
                % new added 
                M = 4;
                pn = sqrt(M);
                power = (2/3)*(M-1);
                dic = 1/sqrt(power);
                % bound = 1.2648;
                bound = pn*dic-dic/2;
                a1 = 2*(sgnRd*bool+sgnId*(~bool)*j);
                a2 = 2*(sgnRd*(~bool)+sgnId*bool*j);
                symnum = ceil(sqrt(mvalue+0.25)-0.5);
                tempadd = zeros(1,sum(1:symnum)*2);
                tempadd(1) = x_hat_det * sqrt(power);
                for indi=1:symnum
                    for indj=(indi-1)*indi+1:indi*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a1*(-1)^(indi+1);
                    end
                    for indj=indi*indi+1:(indi+1)*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a2*(-1)^(indi+1);
                    end
                end
                pp = zeros(1,mvalue);
                sgnRp1 = sign(real(tempadd(1)));
                sgnIp1 = sign(imag(tempadd(1)));
                ttt = floor(sqrt(mvalue-0.1))+1;
                pp(1) = tempadd(1)/sqrt(power);
                for ppi=2:mvalue
                    ptmp = tempadd(ppi)/sqrt(power);
                    bool1 = abs(real(ptmp))>bound;
                    bool2 = abs(imag(ptmp))>bound;
                        ptmp = ptmp*sqrt(power) - (2*ttt)*(bool1*sgnRp1+sqrt(-1)*bool2*sgnIp1);
                    pp(ppi) = ptmp/sqrt(power);
                end
                p = pp;
                         
                enum_tmp = p(1:mvalue);
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
            
        end
        
        function enum = BoundedFastExtendedEnum16Qam_Complex(app,x_hat_tmp,mvalue,num)
            
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam16Slice(app,x_hat_det_real)+Qam16Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                sgnRd = sign(real(d));
                sgnId = sign(imag(d));
                j = sqrt(-1);
                bool = abs(real(d))>abs(imag(d));    
                
                M = 16;
                pn = sqrt(M);
                power = (2/3)*(M-1);
                dic = 1/sqrt(power);
                bound = pn*dic-dic/2;
                a1 = 2*(sgnRd*bool+sgnId*(~bool)*j);
                a2 = 2*(sgnRd*(~bool)+sgnId*bool*j);
                symnum = ceil(sqrt(mvalue+0.25)-0.5);
                tempadd = zeros(1,sum(1:symnum)*2);
                tempadd(1) = x_hat_det * sqrt(power);
                for indi=1:symnum
                    for indj=(indi-1)*indi+1:indi*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a1*(-1)^(indi+1);
                    end
                    for indj=indi*indi+1:(indi+1)*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a2*(-1)^(indi+1);
                    end
                end
                pp = zeros(1,mvalue);
                sgnRp1 = sign(real(tempadd(1)));
                sgnIp1 = sign(imag(tempadd(1)));
                ttt = floor(sqrt(mvalue-0.1))+1;
                pp(1) = tempadd(1)/sqrt(10);
                for ppi=2:mvalue
                    ptmp = tempadd(ppi)/sqrt(power);
                    bool1 = abs(real(ptmp))>bound;
                    bool2 = abs(imag(ptmp))>bound;
                        ptmp = ptmp*sqrt(power) - (2*ttt)*(bool1*sgnRp1+sqrt(-1)*bool2*sgnIp1);
                    pp(ppi) = ptmp/sqrt(power);
                end
                p = pp;
                enum_tmp = p(1:mvalue);
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
        end
        
        function enum = BoundedFastExtendedEnum64Qam_Complex(app,x_hat_tmp,mvalue,num)
                   
            enum = zeros(1,mvalue);
            for i=1:num
                x_hat_det_real = real(x_hat_tmp(i));
                x_hat_det_imag = imag(x_hat_tmp(i));
                x_hat_det = Qam64Slice(app,x_hat_det_real)+Qam64Slice(app,x_hat_det_imag)*sqrt(-1);
                d = x_hat_tmp(i) - x_hat_det;
                sgnRd = sign(real(d));
                sgnId = sign(imag(d));
                j = sqrt(-1);
                bool = abs(real(d))>abs(imag(d));    
                
                M = 64;
                pn = sqrt(M);
                power = (2/3)*(M-1);
                dic = 1/sqrt(power);
                bound = pn*dic-dic/2;
                a1 = 2*(sgnRd*bool+sgnId*(~bool)*j);
                a2 = 2*(sgnRd*(~bool)+sgnId*bool*j);
                symnum = ceil(sqrt(mvalue+0.25)-0.5);
                tempadd = zeros(1,sum(1:symnum)*2);
                tempadd(1) = x_hat_det * sqrt(power);
                for indi=1:symnum
                    for indj=(indi-1)*indi+1:indi*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a1*(-1)^(indi+1);
                    end
                    for indj=indi*indi+1:(indi+1)*indi
                        tempadd(indj+1) = tempadd(indj-1+1) + a2*(-1)^(indi+1);
                    end
                end
                pp = zeros(1,mvalue);
                sgnRp1 = sign(real(tempadd(1)));
                sgnIp1 = sign(imag(tempadd(1)));
                ttt = floor(sqrt(mvalue-0.1))+1;
                pp(1) = tempadd(1)/sqrt(power);
                for ppi=2:mvalue
                    ptmp = tempadd(ppi)/sqrt(power);
                    bool1 = abs(real(ptmp))>bound;
                    bool2 = abs(imag(ptmp))>bound;
                        ptmp = ptmp*sqrt(power) - (2*ttt)*(bool1*sgnRp1+sqrt(-1)*bool2*sgnIp1);
                    pp(ppi) = ptmp/sqrt(power);
                end
                p = pp;
                enum_tmp = p(1:mvalue);
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
        end
        
        function Quanti1 = Qam4Slice(~,Pre_Quanti)
            Quanti0=Pre_Quanti*sqrt(2);
            Quanti1((Quanti0)<0)=-0.7071;
            Quanti1((Quanti0)>=0)=0.7071;
        end
        
        function Quanti1 = Qam16Slice(~,Pre_Quanti)
            Quanti0=Pre_Quanti*sqrt(10);
            Quanti1((Quanti0)<-2)=-0.9487;
            Quanti1(((Quanti0)>=-2)&((Quanti0)<0))=-0.3162;
            Quanti1(((Quanti0)>=0)&((Quanti0)<2))=0.3162;
            Quanti1((Quanti0)>=2)=0.9487;
        end
        
        function Quanti1 = Qam64Slice(~,Pre_Quanti)
            Quanti0=Pre_Quanti*sqrt(42);
            Quanti1(real(Quanti0)<-6)=-1.0801;
            Quanti1((real(Quanti0)>=-6)&(real(Quanti0)<-4))=-0.7715;
            Quanti1((real(Quanti0)>=-4)&(real(Quanti0)<-2))=-0.4629;
            Quanti1((real(Quanti0)>=-2)&(real(Quanti0)<-0))=-0.1543;
            Quanti1((real(Quanti0)>=0)&(real(Quanti0)<2))=0.1543;
            Quanti1((real(Quanti0)>=2)&(real(Quanti0)<4))=0.4629;
            Quanti1((real(Quanti0)>=4)&(real(Quanti0)<6))=0.7715;
            Quanti1(real(Quanti0)>=6)=1.0801;
        end
        
        function resizeframenumber(app)
            snr = app.Min_SNR:app.snrstep:app.Max_SNR;
            newframenum = zeros(1,length(snr));
            newframenum(1:length(app.frame_number)) = app.frame_number;
            if length(snr)>length(app.frame_number)
                for i=length(newframenum)+1:length(snr)
                    newframenum(i) = newframenum(i-1)*1.2;
                end
            end
            app.frame_number = newframenum;
        end
        
        function r = sphdec(app,z, R, symbset, n, radius)
            
            %
            %  SPHDEC: sphere decoder for linear STBC
            %     
            %  R = SPHDEC(H, Y, SYMBSET, RADIUS)
            %
            %  H:        equivalent channel matrix
            %  Y:        received signal vector
            %  SYMBSET:  constellation
            %  RADIUS:   search radius
            %  
            %  author:  Xiaoyong Guo
            %  website: http://www.wordeazy.com
            %
            %  Goto to my website to download the C-MEX version 
            %  of this program. Please Send BUG report to
            %              
            %           guo.xiaoyong@gmail.com
            %
            %  COPYRIGHT (c) 2006-2009,  Xiaoyong Guo
            %
        
            
%             disp(nargin);
            if nargin == 5
                radius = realmax;
            end
        
            % add examine this variable before make it global
            global SPHDEC_RADIUS;
            global RETVAL;
            global TMPVAL;
            global SYMBSETSIZE;
            global SEARCHFLAG;
            SPHDEC_RADIUS = radius;
        
            RETVAL        = zeros(n, 1);
            TMPVAL        = zeros(n, 1);
            SYMBSETSIZE   = length(symbset(:));
            SEARCHFLAG    = 0;
        
            sphdec_core(app,z, R, symbset, n, 0);
        
            if SEARCHFLAG > 0
                r = RETVAL;
            else
                r = 0;
            end
        
        
            clear SPHDEC_RADIUS RETVAL SYMBSETSIZE SEARCHFLAG;
            
        end
        
        function sphdec_core(app,z, R, symbset, layer, dist)

            global SPHDEC_RADIUS;
            global RETVAL;
            global TMPVAL;
            global SYMBSETSIZE;
            global SEARCHFLAG;
        
            if (layer == 1)
                for ii = 1:SYMBSETSIZE
                    TMPVAL(1) = symbset(ii);
                    d = abs(z(1) - R(1,:)*TMPVAL)^2 + dist;
                    if (d <= SPHDEC_RADIUS)
                        RETVAL        =  TMPVAL;
                        SPHDEC_RADIUS =  d;
                        SEARCHFLAG    =  SEARCHFLAG + 1;
                    end
                end
            else
                for ii = 1:SYMBSETSIZE
                    TMPVAL(layer) = symbset(ii);
                    d = abs(z(layer) - R(layer,layer:end)*TMPVAL(layer:end))^2 + dist;
                    if (d <= SPHDEC_RADIUS)
                        sphdec_core(app,z, R, symbset, layer-1, d);
                    end
                end
            end
            
        end
        
        function [c_neighbour,c_nbind] = get_singlelayer_nb(~,c)
            c_neighbour = zeros(length(c),length(c)-1);
            c_nbind = zeros(length(c),length(c)-1);
            for i=1:length(c)
                % get current element
                c_current = c(i);
                index_rest = zeros(1,length(c)-1);
                % get the index of rest of the elements
                ind = 1;
                for j=1:length(c)
                    if i~=j
                        index_rest(ind) = j;
                        ind = ind + 1;
                    end
                end
                tmp = abs(c_current - c);
                tmp = tmp(tmp>10e-10);
                [~,order] = sort(tmp);
                c_nbind(i,:) = index_rest(order);
                c_neighbour(i,:) = c(c_nbind(i,:));
            end 
        end 
        
        function q = IndexOfConstellation(~,c,sym)
            allind = 1:length(c);
            q = allind(abs(c-sym)<10e-5);
        end 
        
        %function [allmlcost,allnb] = MLCostNB(app,H,gm,s_v,c,c_nb)
        function [allmlcost,allnb] = MLCostNB(app,gm,H,s_v,c,c_nb,Nb_1,mt_1)
            % derive all neighbours
            allnb = zeros(app.mt,app.mt*app.Nb);
            allmlcost = zeros(1,app.mt*app.Nb);
        %     allnb = zeros(2*mt,app.mt*N);
        %     allmlcost = zeros(1,app.mt*N);
            ind = 1;
            for i=1:mt_1
                sym = round(gm(i)*10000)/10000;
                allind = 1:16;
                symind = allind(abs(round(c*10000)/10000-sym)<10e-5);
                for j=1:Nb_1
                    allnb(:,ind) = gm;
                    nb = c_nb(symind,j);
                    allnb(i,ind) = nb;
                    allmlcost(ind) = sum(abs(s_v-H*allnb(:,ind)));
                    ind = ind + 1;
                end 
            end 
        %     allmlcost = sum(abs(repmat(s_v,1,mt*N)-H*allnb));    
        end 
        
        %function [l_rep,num_rep,P] = CheckRep3(app,tabumlcost,mlcostm,P,l_rep)
        function [l_rep,num_rep,P] = CheckRep3(~,tabumlcost,mlcostm,P,beta_1,l_rep)
            
            num_rep = 0;
            repeat = 0;
            rep = round(tabumlcost*10000)/10000==round(mlcostm*10000)/10000;
            order = 1:length(tabumlcost);
            repinds = order(rep);
            if length(repinds)>1
                repind = repinds(end-1);
                dist = length(order)-repind;
                if l_rep>0
                    l_rep = ceil((l_rep+dist)/2);
                else
                    l_rep = dist;
                end
                if l_rep>0            
                    P = P + 1;
                    repeat = length(repinds)-1;
                end
            end
            num_rep = num_rep + repeat;
            if P>beta_1*l_rep
                P = max(1,P-1);
            end
        end
        
        %function [u_hat,v_hat,v_hat_hat,q_hat,q_hat_hat,...
        %         gm,tm,l_rep,num_rep,tabugm,tabumlcost,t_flag]...
        %         = TabuMove2Nb_fixed(app,H,tabugm,tabumlcost,tm,gm,s_v,...
        %         allmlcost,allnb,c,P,Power_Mc,c_nb,num_rep,l_rep,iter)
        function [u_hat,v_hat,v_hat_hat,q_hat,q_hat_hat, ...
                gm,tm,l_rep,num_rep,tabugm,tabumlcost,t_flag] ...
                = TabuMove2Nb_fixed(app,tabugm,tabumlcost,tm,gm,...
                H,s_v,allmlcost,allnb,c,c_nbind,Power_Mc,c_nb,Nb_1,mt_1,...
                P,num_rep,l_rep,beta_1,iter,max_iter_1,max_rep_1)
            if isempty(tabumlcost)
                LastMLCost = sum(abs(s_v-H*gm));
            else
            	LastMLCost = min(tabumlcost);
            end
            u_record = 0;
            v_record = 0;
            q_record = 0;
            l_flag = 0;
            t_flag = 0;
            allmlcosttmp = allmlcost;
            while u_record==0||v_record==0
                %% step 1
                % choose the minimun neighbour's ML cost as the next move candidate
                dist2find = 1;
        %         u_record = 0;
        %         v_record = 0;
        %         q_record = 0;
                while dist2find<length(allmlcosttmp)
                    [~,minindex] = min(allmlcosttmp);
                    nbMLCost = allmlcost(minindex);
                    utmp = floor((minindex-1)/Nb_1)+1;
                    vtmp = mod(minindex-1,Nb_1)+1;
                    qtmp = IndexOfConstellation(app,c,gm(utmp));
                    if tm((utmp-1)*Power_Mc+qtmp,vtmp)==0
                        gm_record = allnb(:,minindex);
                        u_record = utmp;
                        v_record = vtmp;
                        q_record = qtmp;
                        mlcostm_record = nbMLCost;
                        break;
                    end
                    dist2find = dist2find + 1;
                    allmlcosttmp(minindex) = max(allmlcosttmp)+10;
                end
                % if not find, decrease the tabu matrix by the minimun entry, else go to step 2
                if u_record==0||v_record==0
                    tm(tm>0) = tm(tm>0)-min(tm(tm>0));
                    l_flag = 0;
                else
                    % neighbour parameters
                    u_hat = u_record;
                    v_hat = v_record;
                    q_hat = q_record;
                    gm_hat = gm_record;
                   %% step 2 
                   % update search queue for checking repetitions
                    % the starting point parameters
        %             [~,nb_order] = sort(abs(c-gm_hat(u_hat)));
        %             nb_tmp = c(nb_order);
        %             nb_tmp = nb_tmp(2:end);
        %             nbind = 1:length(nb_tmp);
                    u_hat_hat = u_hat;
        %             v_hat_hat = nbind(round(nb_tmp*100)/100==round(gm(u_hat)*100)/100);
                    %[~,allnb_hat] = MLCostNB(app,H,gm_hat,s_v,c,c_nb);
                    [~,allnb_hat] = MLCostNB(app,gm,H,s_v,c,c_nb,Nb_1,mt_1);
                    allnab_hat_ulevel = allnb_hat(u_hat,:);
                    nbind = 1:length(allnab_hat_ulevel);
                    v_hat_hat = mod(nbind(round(allnab_hat_ulevel*100)/100==round(gm(u_hat)*100)/100)-1,app.Nb)+1;
                    q_hat_hat = IndexOfConstellation(app,c,gm_hat(u_hat));
        %             if mlcostm_record<LastMLCost
        %                 tm((u_hat-1)*Power_Mc+q_hat,v_hat)=0;
        %                 tm((u_hat_hat-1)*Power_Mc+q_hat_hat,v_hat_hat)=0;
                        gm = gm_hat;
                        mlcostm = mlcostm_record;
        %             else
                        tm((u_hat-1)*Power_Mc+q_hat,v_hat)=P+1;
                        tm((u_hat_hat-1)*Power_Mc+q_hat_hat,v_hat_hat)=P+1;
        %                 l_flag = 1;
        %                 mlcostm = LastMLCost;
        %             end   
                    tabugm = [tabugm gm];
                    tabumlcost = [tabumlcost mlcostm];
                    % check repetition length
                    %[l_rep,num_rep,P] = CheckRep3(app,tabumlcost,mlcostm,P,l_rep);
                    [l_rep,num_rep,P] = CheckRep3(app,tabumlcost,mlcostm,P,beta_1,l_rep);
                    
                end
            end
            %% step 3
            % update tabu matrix
            for r=1:mt_1*Power_Mc
                for s=1:Nb_1
                    tm(r,s) = max(tm(r,s)-1,0);
                end
            end
            % check stopping criterion
            if iter>=max_iter_1
                t_flag = 1;
            end
        %     if (num_rep>max_rep)&&(l_flag==1)
        %         t_flag = 1;
        %     end
        %     fprintf('%s %d %s\n','finish TabuMove2Nb at iteration',m,'!!!!!!!');
        end
        
        %function [TS_v,tm,l_rep,num_rep,tabugm,tabumlcost] ...
        %        = tabu_search_det_fixed(app,H,ZF_v,s_v,c,c_nb,Power_Mc)
        function [TS_v,tm,l_rep,num_rep,tabugm,tabumlcost] ...
                = tabu_search_det_fixed(app,H,ZF_v,s_v,c,c_nb, ...
                c_nbind,P_init_1,Nb_1,mt_1,Power_Mc,max_rep_1,max_iter_1,beta_1)
            %% tabu matrix definitions
            tm = zeros(mt_1*Power_Mc,Nb_1);
            
            %% tabu search initialization
            gm = ZF_v; % the best solution vector at mth iteration
            l_rep = 0; % the average lenght between two successive occurrences of a solution vector
            P = P_init_1; % the tabu period
            iter = 0; % iteration index
            TS_v = gm;
            
            %% iterative search
            % first iteration based on ZF result
            num_rep = 0;
            tabugm = ZF_v;
            tabumlcost = sum(abs(s_v-H*ZF_v));
            gm = ZF_v;
            t_flag = 0;
            while t_flag==0
                %[allmlcost,allnb] = MLCostNB(app,H,gm,s_v,c,c_nb);
                [allmlcost,allnb] = MLCostNB(app,gm,H,s_v,c,c_nb,Nb_1,mt_1);
                %[~,~,~,~,~,gm,tm,...
                %l_rep,num_rep,tabugm,tabumlcost,t_flag] ...
                %= TabuMove2Nb_fixed(app,H,tabugm,tabumlcost,tm,gm,s_v,...
                %allmlcost,allnb,c,P,Power_Mc,c_nb,num_rep,l_rep,iter);
                [u_hat,v_hat,v_hat_hat,q_hat,q_hat_hat,gm,tm,...
                l_rep,num_rep,tabugm,tabumlcost,t_flag] = TabuMove2Nb_fixed(app,tabugm,...
                tabumlcost,tm,gm,H,s_v,allmlcost,allnb,c,c_nbind,Power_Mc,c_nb,Nb_1,mt_1,...
                P,num_rep,l_rep,beta_1,iter,max_iter_1,max_rep_1);
                iter = iter + 1;
            end
            if t_flag==1    
                [~,minind]=min(tabumlcost);
                TS_v = tabugm(:,minind);
            end
        end
        
        function enum = FastEnum4Qam_Real(~,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num 
        %         x_hat_det = Qam16Slice(x_hat_tmp(i));
        %         d = x_hat_tmp(i) - x_hat_det;
            %     bool = abs(x_hat_det)<0.9487;
        %         switch(mvalue)
        %             case 1
        %                 enum_tmp = x_hat_det;
        %             case 2
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d)];
        %             case 3
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d)];
        %             case 4     
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d) x_hat_det+2*0.6325*sign(d)];
        %             otherwise
        %                 disp('no larger than 4 for 16QAM');
        %         end
        %         enum = [enum enum_tmp];
        %     end 
                c = [-0.7071 0.7071];
                d = abs(x_hat_tmp(i) - c);
                [~, order] = sort(d,'ascend');
                switch(mvalue) 
                    case 1
                        enum_tmp = c(order(1));
                    case 2
                        enum_tmp = c(order(1:2));
                    otherwise
                        enum_tmp = c;
                end 
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end 
        end 
        
        function enum = FastEnum16Qam_Real(~,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num
        %         x_hat_det = Qam16Slice(x_hat_tmp(i));
        %         d = x_hat_tmp(i) - x_hat_det;
            %     bool = abs(x_hat_det)<0.9487;
        %         switch(mvalue)
        %             case 1
        %                 enum_tmp = x_hat_det;
        %             case 2
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d)];
        %             case 3
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d)];
        %             case 4     
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d) x_hat_det+2*0.6325*sign(d)];
        %             otherwise
        %                 disp('no larger than 4 for 16QAM');
        %         end
        %         enum = [enum enum_tmp];
        %     end
                c = [-0.9487 -0.3162 0.3162 0.9487];
                d = abs(x_hat_tmp(i) - c);
                [~, order] = sort(d,'ascend');
                switch(mvalue)
                    case 1
                        enum_tmp = c(order(1));
                    case 2
                        enum_tmp = c(order(1:2));
                    case 3
                        enum_tmp = c(order(1:3));
                    case 4     
                        enum_tmp = c(order(1:4));
                    otherwise
                        enum_tmp = c;
                end
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
        end
        
        function enum = FastEnum64Qam_Real(~,x_hat_tmp,mvalue,num)
            enum = zeros(1,mvalue);
            for i=1:num
        %         x_hat_det = Qam16Slice(x_hat_tmp(i));
        %         d = x_hat_tmp(i) - x_hat_det;
            %     bool = abs(x_hat_det)<0.9487;
        %         switch(mvalue)
        %             case 1
        %                 enum_tmp = x_hat_det;
        %             case 2
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d)];
        %             case 3
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d)];
        %             case 4     
        %                 enum_tmp = [x_hat_det x_hat_det+0.6325*sign(d) ...
        %                     x_hat_det-0.6325*sign(d) x_hat_det+2*0.6325*sign(d)];
        %             otherwise
        %                 disp('no larger than 4 for 16QAM');
        %         end
        %         enum = [enum enum_tmp];
        %     end
                c = [-0.9487 -0.3162 0.3162 0.9487];
                d = abs(x_hat_tmp(i) - c);
                [~, order] = sort(d,'ascend');
                switch(mvalue)
                    case 1
                        enum_tmp = c(order(1));
                    case 2
                        enum_tmp = c(order(1:2));
                    case 3
                        enum_tmp = c(order(1:3));
                    case 4     
                        enum_tmp = c(order(1:4));
                    otherwise
                        enum_tmp = c;
                end
                enum((i-1)*mvalue+1:i*mvalue) = enum_tmp;
            end
        end
        
        function MF(app)
            
            clc;
            
            %% simulation body
            MF_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_MF(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            w=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_MF=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,app.index/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % MF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=H'*s;      % MMSE
                    for frame=1:app.frame_length
                        %%%%%%%%%%%%%%%%%%%%%%%%
                        % Symbols to Bits 
                        %%%%%%%%%%%%%%%%%%%%%%%%
                        De_MF=QAM_Demod(app,r(:,frame),app.index/2);
                        Z_MF(:,(frame-1)*app.index+1:frame*app.index) = reshape(SymbolToBitStream(app,De_MF,app.index/2),app.index,[])';
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Count The Error
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    MF_error(w)=sum(sum(abs(Encoded_Source - Z_MF))) + MF_error(w);        
                end
                BER_MF(w)=MF_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            
            %% Plot BER
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_MF,'-y.');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_MF];
            app.plot_legend = {app.plot_legend{1:end},'MF'};
            
        end

        function ZF(app)
            
            clc;
            
            %% simulation body
            ZF_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_ZF(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            w=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_ZF=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,app.index/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      % ZF
                    if app.With_SIC == 0
                        for frame=1:app.frame_length
                            %%%%%%%%%%%%%%%%%%%%%%%%
                            % Symbols to Bits 
                            %%%%%%%%%%%%%%%%%%%%%%%%
                            De_ZF=QAM_Demod(app,r(:,frame),app.index/2);
                            Z_ZF(:,(frame-1)*app.index+1:frame*app.index) = reshape(SymbolToBitStream(app,De_ZF,app.index/2),app.index,[])';
                        end
                    else
                        M=app.mt;
                        cols_left = 1:M;
                        mimo_ch_iter = H;
                        mimo_ch_index=zeros(1,M);
                        record_tmp = zeros(M,M);
                        for kk_ord = M:-1:1 
                            mimo_ch_inv = pinv(mimo_ch_iter);
                            inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                            record_tmp(:,kk_ord) = inv_norms;
                            [~, ~, cols_index] = find(cols_left);
                            inv_norms = inv_norms(cols_index);
                            % Require to modify for MIMO 88
                %             if kk_ord > (M-Fs)
                %                 [min_val min_index] = max(inv_norms);
                %             else
                                [~, min_index] = min(inv_norms);
                %             end            
                            cols_left(cols_index(min_index)) = 0;
                            mimo_ch_index(kk_ord) = cols_index(min_index);            
                            temp_ch = zeros(M, M);
                            temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                            mimo_ch_iter = mimo_ch_iter - temp_ch;
                        end         
                        order=mimo_ch_index;
                        [~,reorder]=sort(order);
                        H_tmp = [H;eye(app.mt,app.mt)*10^(-SNR/10)];
                        H4 = H_tmp(:,mimo_ch_index);
                        [~,U]=qr(H4);
                        U = U(1:app.mt,1:app.mt);
                        UU=zeros(M,M);
                        UUU=zeros(1,M);
                        for i=1:M 
                            UU(i,i)=U(i,i)^2;
                            UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                            UUU(i)=UU(i,i);
                        end 
        
                        for frame=1:app.frame_length
            
                            % ZF-SIC
                            %%%%%%%%%%%%%%%%%%%%%%%%%%
                            %Modefy channel matrix
                            %%%%%%%%%%%%%%%%%%%%%%%%%%
                            zf=r(:,frame);
                            x_hat=zf(order);
                            Number_br = 1;
                            sd=zeros(M,Number_br);     %record all possible SD symbols
                            s_zf0=zeros(M,Number_br);  %operate one
                            s_zf1=zeros(M,Number_br);  %store one
            %                 Pre_PED0=zeros(1,Number_br); %operate one
                            Pre_PED1=zeros(M,Number_br); %store one, record the PED, rather than APED
            %                 Pre_Quanti=zeros(1,Number_br); % Record data before quanti
                            %Full search APEDs
                            ii=0;
                            mmul = 1;
                %             for i=M:-1:(M-Fs+1)
                            for i=M:-1:1
                                ii=ii+1;
                                mvalue = 1;
                                Power_Mc_real = mvalue;
                                    x_hat_tmp = x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                                cc = zeros(1,mmul*mvalue);
                                for j=1:mmul                                
                                    switch(app.index)
                                        case 2
                                            enum = FastEnum4Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                        case 4
                                            enum = FastEnum16Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                        case 6
                                            enum = FastEnum64Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                        otherwise
                                            disp('Unexpected Constellation Index');
                                    end
                                    s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                    if i~=M
                                        s_zf0(i+1:M,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:M,j),1,Power_Mc_real);
                                    end
                                    cc((j-1)*mvalue+1:j*mvalue) = enum;
                                end
                                Pre_PED0=s_zf0(i,:);
                                if i~=M
                                    Pre_PED0=Pre_PED0+UU(i,i+1:M)*s_zf0(i+1:M,:);
                                end
                                s_zf1=s_zf0;
                                mmul = mmul * mvalue;
                                REP_PED=Pre_PED0(1:mmul);
                                Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                                sd(i,:)=reshape(repmat(cc,Number_br/length(REP_PED),mmul/length(cc)),1,[]);
                            end
                            PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                            APED=sum(PED);
                            [~,Index_SD] = min(APED);
                            s_SD_ori=sd(:,Index_SD);
                            s_SD=s_SD_ori(reorder);     %resort
                            s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                            Z_ZF(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                        end
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Count The Error
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    ZF_error(w)=sum(sum(abs(Encoded_Source - Z_ZF))) + ZF_error(w);        
                end
                BER_ZF(w)=ZF_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            
            %% Plot BER
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_ZF,'-c.');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,ZF,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_ZF];
            if app.With_SIC == 0
                app.plot_legend = {app.plot_legend{1:end},'ZF'};
            else
                app.plot_legend = {app.plot_legend{1:end},'ZF-SIC'};
            end
            
        end
        
        function MMSE(app)
            
            clc;           
            
            %% simulation body
            MMSE_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_MMSE(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            w=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_MMSE=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,app.index/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % MMSE
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H+eye(app.nr)*10^(-SNR/10))*H'*s;      % MMSE
                    if app.With_SIC == 0
                        for frame=1:app.frame_length
                            %%%%%%%%%%%%%%%%%%%%%%%%
                            % Symbols to Bits 
                            %%%%%%%%%%%%%%%%%%%%%%%%
                            De_MMSE=QAM_Demod(app,r(:,frame),app.index/2);
                            Z_MMSE(:,(frame-1)*app.index+1:frame*app.index) = reshape(SymbolToBitStream(app,De_MMSE,app.index/2),app.index,[])';
                        end
                    else
                        M=app.mt;
                        cols_left = 1:M;
                        mimo_ch_iter = H;
                        mimo_ch_index=zeros(1,M);
                        record_tmp = zeros(M,M);
                        for kk_ord = M:-1:1 
                            mimo_ch_inv = pinv(mimo_ch_iter);
                            inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                            record_tmp(:,kk_ord) = inv_norms;
                            [~, ~, cols_index] = find(cols_left);
                            inv_norms = inv_norms(cols_index);
                            % Require to modify for MIMO 88
                %             if kk_ord > (M-Fs)
                %                 [min_val min_index] = max(inv_norms);
                %             else
                                [~, min_index] = min(inv_norms);
                %             end            
                            cols_left(cols_index(min_index)) = 0;
                            mimo_ch_index(kk_ord) = cols_index(min_index);            
                            temp_ch = zeros(M, M);
                            temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                            mimo_ch_iter = mimo_ch_iter - temp_ch;
                        end         
                        order=mimo_ch_index;
                        [~,reorder]=sort(order);
                        H_tmp = [H;eye(app.mt,app.mt)*10^(-SNR/10)];
                        H4 = H_tmp(:,mimo_ch_index);
                        [~,U]=qr(H4);
                        U = U(1:app.mt,1:app.mt);
                        UU=zeros(M,M);
                        UUU=zeros(1,M);
                        for i=1:M 
                            UU(i,i)=U(i,i)^2;
                            UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                            UUU(i)=UU(i,i);
                        end 
        
                        for frame=1:app.frame_length
            
                            % MMSE-SIC
                            %%%%%%%%%%%%%%%%%%%%%%%%%%
                            %Modefy channel matrix
                            %%%%%%%%%%%%%%%%%%%%%%%%%%
                            mmse=r(:,frame);
                            x_hat=mmse(order);
                            Number_br = 1;
                            sd=zeros(M,Number_br);     %record all possible SD symbols
                            s_zf0=zeros(M,Number_br);  %operate one
                            s_zf1=zeros(M,Number_br);  %store one
            %                 Pre_PED0=zeros(1,Number_br); %operate one
                            Pre_PED1=zeros(M,Number_br); %store one, record the PED, rather than APED
            %                 Pre_Quanti=zeros(1,Number_br); % Record data before quanti
                            %Full search APEDs
                            ii=0;
                            mmul = 1;
                %             for i=M:-1:(M-Fs+1)
                            for i=M:-1:1
                                ii=ii+1;
                                mvalue = 1;
                                Power_Mc_real = mvalue;
                                    x_hat_tmp = x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                                cc = zeros(1,mmul*mvalue);
                                for j=1:mmul                                
                                    switch(app.index)
                                        case 2
                                            enum = FastEnum4Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                        case 4
                                            enum = FastEnum16Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                        case 6
                                            enum = FastEnum64Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                        otherwise
                                            disp('Unexpected Constellation Index');
                                    end
                                    s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                    if i~=M
                                        s_zf0(i+1:M,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:M,j),1,Power_Mc_real);
                                    end
                                    cc((j-1)*mvalue+1:j*mvalue) = enum;
                                end
                                Pre_PED0=s_zf0(i,:);
                                if i~=M
                                    Pre_PED0=Pre_PED0+UU(i,i+1:M)*s_zf0(i+1:M,:);
                                end
                                s_zf1=s_zf0;
                                mmul = mmul * mvalue;
                                REP_PED=Pre_PED0(1:mmul);
                                Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                                sd(i,:)=reshape(repmat(cc,Number_br/length(REP_PED),mmul/length(cc)),1,[]);
                            end
                            PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                            APED=sum(PED);
                            [~,Index_SD] = min(APED);
                            s_SD_ori=sd(:,Index_SD);
                            s_SD=s_SD_ori(reorder);     %resort
                            s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                            Z_MMSE(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                        end
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Count The Error
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    MMSE_error(w)=sum(sum(abs(Encoded_Source - Z_MMSE))) + MMSE_error(w);        
                end
                BER_MMSE(w)=MMSE_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            
            %% Plot BER
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_MMSE,'-c*');
            set(app.UIAxes,'yscale','log');
            hold(app.UIAxes,app.figurehold);
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,MMSE,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_MMSE];
            if app.With_SIC == 0
                app.plot_legend = {app.plot_legend{1:end},'MMSE'};
            else
                app.plot_legend = {app.plot_legend{1:end},'MMSE-SIC'};
            end
            
        end
        
        function KBEST(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));
            Number_br=app.K*2^Mc;%Parallel branches
            
            %% simulation body
            w=0;
            c=mapping;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));   
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);     
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    cols_left = 1:app.mt;
                    mimo_ch_iter = H;
                    mimo_ch_index=zeros(1,app.mt);
                    for kk_ord = app.mt:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        [~, ~, cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
                        % Require to modify for MIMO 88
            %             if kk_ord > (mt-Fs)
            %                 [min_val min_index] = max(inv_norms);
            %             else
                            [~,min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(app.nr, app.mt);
                        temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~, U]=qr(H4);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(app.mt,app.mt);
                    UUU=zeros(1,app.mt);
                    for i=1:app.mt
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:app.mt)=U(i,i+1:app.mt)./U(i,i);
                        UUU(i)=UU(i,i);
                    end                
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length            
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % K-BEST
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf(order);
                        sd=zeros(app.mt,app.K);     %record all possible SD symbols
                        const_order = zeros(app.mt,app.K);
                        zf_survive = zeros(app.mt,app.K);
                        PED_survive = zeros(app.mt,app.K);
                        s_zf0=zeros(app.mt,Number_br);  %operate one
                        s_zf1=zeros(app.mt,Number_br);  %store one
                        Pre_PED1=zeros(app.mt,app.K); %store one, record the PED, rather than APED
                        PED_layer = zeros(app.mt,Number_br);
                        for i=app.mt:-1:1
                            % s-zf
                            c_all = repmat(c,1,app.K);
                            s_zf0(i,:)=c_all-x_hat(i);
                            if i~=app.mt
                                s_zf0(i+1:app.mt,:)=s_zf1(i+1:app.mt,:);
                            end
                            % zftmp
                            Pre_Quanti=x_hat(i)-UU(i,i+1:app.mt)*s_zf1(i+1:app.mt,:);
                            % s-zftmp
                            Pre_PED0_layer = c_all - Pre_Quanti;
                            % ped
                            PED_layer(i,:) = repmat(UUU(i),length(Pre_PED0_layer),1)'.*(abs(Pre_PED0_layer).^2);
                            % aped
                            APED_full = sum(PED_layer);
                            % K min aped
                            if i==app.mt
                                kminindex = KMinPED(app,APED_full(1:2^Mc));
                            else
                                kminindex = KMinPED(app,APED_full);
                            end
                            if i==app.mt
                                % update ped
                                PED_survive(i,:) = PED_layer(i,kminindex);
                                PED_layer(i,:) = reshape(repmat(PED_survive(i,:),2^Mc,1),1,[]);
                                % update last s-zf
                                zf_survive(i,:) = s_zf0(i,kminindex);
                                s_zf1(i,:)=reshape(repmat(zf_survive(i,:),2^Mc,1),1,[]);
                                % save K zftmp
                                REP_PED=Pre_PED0_layer(kminindex);
                                Pre_PED1(i,:)=REP_PED;
                                % save K slice symbols
                                sd(i,:)=c_all(kminindex);
                                const_order(i,:) = ConstellationIndex(app,c,sd(i,:));
                            else
                                ind_tmp = ceil(kminindex/2^Mc);
                                % update ped
                                PED_survive(i,:) = PED_layer(i,kminindex);
                                if i~=1
                                    PED_layer(i,:) = reshape(repmat(PED_survive(i,:),2^Mc,1),1,[]);
                                end
                                PED_survive_tmp = PED_survive(i+1:app.mt,ind_tmp);
                                PED_survive(i+1:app.mt,:) = PED_survive_tmp;
                                for k=i+1:app.mt
                                    PED_layer(k,:)=reshape(repmat(PED_survive(k,:),2^Mc,1),1,[]);
                                end
                                % update last s-zf
                                zf_survive(i,:) = s_zf0(i,kminindex);
                                s_zf1(i,:)=reshape(repmat(zf_survive(i,:),2^Mc,1),1,[]);
                                zf_survive_tmp = zf_survive(i+1:app.mt,ind_tmp);
                                zf_survive(i+1:app.mt,:) = zf_survive_tmp;
                                for k=i+1:app.mt
                                    s_zf1(k,:)=reshape(repmat(zf_survive(k,:),2^Mc,1),1,[]);
                                end
                                % save K zftmp
                                REP_PED=Pre_PED0_layer(kminindex);
                                Pre_PED1(i,:)=REP_PED;
                                Pre_PED1_tmp = Pre_PED1(i+1:app.mt,ind_tmp);
                                Pre_PED1(i+1:app.mt,:) = Pre_PED1_tmp;
                                % save K slice symbols
                                sd(i,:)=c_all(kminindex);
                                const_order(i,:) = ConstellationIndex(app,c,sd(i,:));
                                sd_tmp = sd(i+1:app.mt,ind_tmp);
                                sd(i+1:app.mt,:) = sd_tmp;
                                const_order_tmp = const_order(i+1:app.mt,ind_tmp);
                                const_order(i+1:app.mt,ind_tmp) = const_order_tmp;
                            end
                        end
                        PED=repmat(UUU,app.K,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori(reorder);     %resort
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-bv');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,KBEST,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            legendstr = strcat('KBEST',' K=',num2str(app.K));
            app.plot_legend = {app.plot_legend{1:end},legendstr};
            
        end
        
        function RKBEST(app)
            
            clc;
            
            %% simulation setup
            M=2*app.mt;
            mapping = mapping_func(app);
            Mc=log2(length(mapping))/2;
            Number_br=app.K*2^Mc;%Parallel branches
            
            %% simulation body
            w=0;
            c=mapping;
            c = unique(real(c));
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    base = 2.^(app.index-1:-1:0);
                    Base=repmat(base,app.mt,app.frame_length);
                    Dec=Base.*Encoded_Source;
                    Dec1=sum(reshape(reshape(Dec',1,[]),app.index,[]));
                    helpmap=(0:2^app.index:(app.frame_length*app.mt-1)*2^app.index);
                    Dec2=Dec1+helpmap+1;
                    Map=repmat(mapping,1,app.frame_length*app.mt);
                    Dec3=Map(Dec2);
                    Normalized_Modulated_Source=transpose(reshape(Dec3,app.frame_length,[]));      
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %         h_r=randn(nr,mt)/sqrt(2);
            %         h_j=randn(nr,mt)/sqrt(2);
            %         H=h_r+sqrt(-1)*h_j;
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);     
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    H_r=real(H);
                    H_i=imag(H);
                    H_cat_0=cat(2, H_r, -H_i);
                    H_cat_1=cat(2, H_i, H_r);
                    H_Real=cat(1, H_cat_0, H_cat_1);
                    
                    cols_left = 1:M;
                    mimo_ch_iter = H_Real;
                    mimo_ch_index=zeros(1,M);
                    for kk_ord = M:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        [~, ~, cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
                        % Require to modify for MIMO 88
            %             if kk_ord > (M-Fs)
            %                 [min_val min_index] = max(inv_norms);
            %             else
                            [~,min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(M, M);
                        temp_ch(:,cols_index(min_index)) = H_Real(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H_Real(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~, U]=qr(H4);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(M,M);
                    UUU=zeros(1,M);
                    for i=1:M
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                        UUU(i)=UU(i,i);
                    end                
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length            
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % K-BEST
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        zf_real=cat(1,real(zf),imag(zf));
                        x_hat=zf_real(order);
                        sd=zeros(M,app.K);     %record all possible SD symbols
                        const_order = zeros(M,app.K);
                        zf_survive = zeros(M,app.K);
                        PED_survive = zeros(M,app.K);
                        s_zf0=zeros(M,Number_br);  %operate one
                        s_zf1=zeros(M,Number_br);  %store one
                        Pre_PED1=zeros(M,app.K); %store one, record the PED, rather than APED
                        PED_layer = zeros(M,Number_br);
                        for i=M:-1:1
                            % s-zf
                            c_all = repmat(c,1,app.K);
                            s_zf0(i,:)=c_all-x_hat(i);
                            if i~=M
                                s_zf0(i+1:M,:)=s_zf1(i+1:M,:);
                            end
                            % zftmp
                            Pre_Quanti=x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                            % s-zftmp
                            Pre_PED0_layer = c_all - Pre_Quanti;
                            % ped
                            PED_layer(i,:) = repmat(UUU(i),length(Pre_PED0_layer),1)'.*(abs(Pre_PED0_layer).^2);
                            % aped
                            APED_full = sum(PED_layer);
                            % K min aped
                            if i==M
                                kminindex = KMinPED(app,APED_full(1:2^Mc));
                            else
                                kminindex = KMinPED(app,APED_full);
                            end
                            if i==M
                                % update ped
                                PED_survive(i,:) = PED_layer(i,kminindex);
                                PED_layer(i,:) = reshape(repmat(PED_survive(i,:),2^Mc,1),1,[]);
                                % update last s-zf
                                zf_survive(i,:) = s_zf0(i,kminindex);
                                s_zf1(i,:)=reshape(repmat(zf_survive(i,:),2^Mc,1),1,[]);
                                % save K zftmp
                                REP_PED=Pre_PED0_layer(kminindex);
                                Pre_PED1(i,:)=REP_PED;
                                % save K slice symbols
                                sd(i,:)=c_all(kminindex);
                                const_order(i,:) = ConstellationIndex(app,c,sd(i,:));
                            else
                                ind_tmp = ceil(kminindex/2^Mc);
                                % update ped
                                PED_survive(i,:) = PED_layer(i,kminindex);
                                if i~=1
                                    PED_layer(i,:) = reshape(repmat(PED_survive(i,:),2^Mc,1),1,[]);
                                end
                                PED_survive_tmp = PED_survive(i+1:M,ind_tmp);
                                PED_survive(i+1:M,:) = PED_survive_tmp;
                                for k=i+1:M
                                    PED_layer(k,:)=reshape(repmat(PED_survive(k,:),2^Mc,1),1,[]);
                                end
                                % update last s-zf
                                zf_survive(i,:) = s_zf0(i,kminindex);
                                s_zf1(i,:)=reshape(repmat(zf_survive(i,:),2^Mc,1),1,[]);
                                zf_survive_tmp = zf_survive(i+1:M,ind_tmp);
                                zf_survive(i+1:M,:) = zf_survive_tmp;
                                for k=i+1:M
                                    s_zf1(k,:)=reshape(repmat(zf_survive(k,:),2^Mc,1),1,[]);
                                end
                                % save K zftmp
                                REP_PED=Pre_PED0_layer(kminindex);
                                Pre_PED1(i,:)=REP_PED;
                                Pre_PED1_tmp = Pre_PED1(i+1:M,ind_tmp);
                                Pre_PED1(i+1:M,:) = Pre_PED1_tmp;
                                % save K slice symbols
                                sd(i,:)=c_all(kminindex);
                                const_order(i,:) = ConstellationIndex(app,c,sd(i,:));
                                sd_tmp = sd(i+1:M,ind_tmp);
                                sd(i+1:M,:) = sd_tmp;
                                const_order_tmp = const_order(i+1:M,ind_tmp);
                                const_order(i+1:M,ind_tmp) = const_order_tmp;
                            end
                        end
                        PED=repmat(UUU,app.K,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD_real=s_SD_ori(reorder);     %resort
                        s_SD_0=reshape(s_SD_real,[],2);
                        s_SD=s_SD_0(:,1)+s_SD_0(:,2)*sqrt(-1);
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end 
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-b^');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,KBEST,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            legendstr = strcat('RKBEST',' K=',num2str(app.K));
            app.plot_legend = {app.plot_legend{1:end},legendstr};
        end
        

        function SSFE(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));
            
            %% simulation body
            mul = 1;
            mstr = app.m{1};
            dotind = find(mstr==',');
            dig = ones(1,length(dotind)+1);
            strind = 1;
            for i=1:length(dig)-1
                dig(i) = str2double(mstr(strind:dotind(i)-1));
                strind = dotind(i)+1;
            end
            dig(length(dig)) = str2double(mstr(strind:end));
            for muli = 1:length(dig)
                mul = mul * dig(muli);
            end
            Number_br = mul;
            w=0;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length); 
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));      
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);     
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
                    cols_left = 1:app.mt;
                    mimo_ch_iter = H;
                    mimo_ch_index=zeros(1,app.mt);
                    record_tmp = zeros(app.nr,app.mt);
                    for kk_ord = app.mt:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        record_tmp(:,kk_ord) = inv_norms;
                        [~,~,cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
            %             if kk_ord > (mt-Fs)
            %                 [~, min_index] = max(inv_norms);
            %             else
                            [~, min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(app.nr, app.mt);
                        temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~, U]=qr(H4);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(app.mt,app.mt);
                    UUU=zeros(1,app.mt);
                    for i=1:app.mt
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:app.mt)=U(i,i+1:app.mt)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length       
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % SSFE
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf(order);
                        sd=zeros(app.mt,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(app.mt,Number_br);  %operate one
                        s_zf1=zeros(app.mt,Number_br);  %store one
                        Pre_PED1=zeros(app.mt,Number_br); %store one, record the PED, rather than APED
                        % Partial search APEDs
                        ii=0;
                        mmul = 1;
                        for i=app.mt:-1:1
                            ii=ii+1;
                            mvalue = dig(ii);
                            Power_Mc_real = mvalue;
                            x_hat_tmp = x_hat(i)-UU(i,i+1:app.mt)*s_zf1(i+1:app.mt,:);
                            c = zeros(1,mmul*mvalue);
                            for j=1:mmul
                                switch(app.index)
                                    case 2
                                        enum = FastEnum4Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 4
                                        enum = FastEnum16Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 6
                                        enum = FastEnum64Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                    otherwise
                                        disp('Unexpected Constellation Index');
                                end
                                s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                if i~=app.mt
                                    s_zf0(i+1:app.mt,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:app.mt,j),1,Power_Mc_real);
                                end
                                c((j-1)*mvalue+1:j*mvalue) = enum;
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=app.mt
                                Pre_PED0=Pre_PED0+UU(i,i+1:app.mt)*s_zf0(i+1:app.mt,:);
                            end
                            s_zf1=s_zf0;
                            mmul = mmul * mvalue;
                            REP_PED=Pre_PED0(1:mmul);
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),mmul/length(c)),1,[]);
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori(reorder);     %resort            
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])'; 
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);        
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end            
            %% Plot BER
            [strtmp,~] = sprintf('%d',dig(1));
            str = strtmp;
            for i=2:length(dig)
                [strtmp,~] = sprintf('%d',dig(i));
                str = strcat(str,',',strtmp);
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-gs');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,SSFE,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            legendstr = strcat('SSFE',' m=[',app.m,']');
            app.plot_legend = {app.plot_legend{1:end},legendstr{1}};
            
        end
        
        function RSSFE(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
%             Mc=log2(length(mapping))/2;
            M = app.mt*2;
            
            %% simulation body
            mul = 1;
            mstr = app.m{1};
            dotind = find(mstr==',');
            dig = ones(1,length(dotind)+1);
            strind = 1;
            for i=1:length(dig)-1
                dig(i) = str2double(mstr(strind:dotind(i)-1));
                strind = dotind(i)+1;
            end
            dig(length(dig)) = str2double(mstr(strind:end));
            for muli = 1:length(dig)
                mul = mul * dig(muli);
            end
            Number_br = mul;
            w=0;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length); 
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    base = 2.^(app.index-1:-1:0);
                    Base=repmat(base,app.mt,app.frame_length);
                    Dec=Base.*Encoded_Source;
                    Dec1=sum(reshape(reshape(Dec',1,[]),app.index,[]));
                    helpmap=(0:2^app.index:(app.frame_length*app.mt-1)*2^app.index);
                    Dec2=Dec1+helpmap+1;
                    Map=repmat(mapping,1,app.frame_length*app.mt);
                    Dec3=Map(Dec2);
                    Normalized_Modulated_Source=transpose(reshape(Dec3,app.frame_length,[]));      
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %         h_r=randn(nr,mt)/sqrt(2);
            %         h_j=randn(nr,mt)/sqrt(2);
            %         H=h_r+sqrt(-1)*h_j;
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);     
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
                    % Real dimension converting
                    H_r=real(H);
                    H_i=imag(H);
                    H_cat_0=cat(2, H_r, -H_i);
                    H_cat_1=cat(2, H_i, H_r);
                    H_Real=cat(1, H_cat_0, H_cat_1);
                    
                    cols_left = 1:M;
                    mimo_ch_iter = H_Real;
                    mimo_ch_index=zeros(1,M);
                    record_tmp = zeros(M,M);
                    for kk_ord = M:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        record_tmp(:,kk_ord) = inv_norms;
                        [~,~,cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
            %             if kk_ord > (M-Fs)
            %                 [~, min_index] = max(inv_norms);
            %             else
                            [~, min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(M, M);
                        temp_ch(:,cols_index(min_index)) = H_Real(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H_Real(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~, U]=qr(H4);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(M,M);
                    UUU=zeros(1,M);
                    for i=1:M
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length       
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % SSFE
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        zf_real=cat(1,real(zf),imag(zf));
                        x_hat=zf_real(order);
                        sd=zeros(M,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(M,Number_br);  %operate one
                        s_zf1=zeros(M,Number_br);  %store one
                        Pre_PED1=zeros(M,Number_br); %store one, record the PED, rather than APED
                        % Partial search APEDs
                        ii=0;
                        mmul = 1;
                        for i=M:-1:1
                            ii=ii+1;
                            mvalue = dig(ii);
                            Power_Mc_real = mvalue;
                            x_hat_tmp = x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                            c = zeros(1,mmul*mvalue);
                            for j=1:mmul
                                switch(app.index)
                                    case 2
                                        enum = FastEnum4Qam_Real(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 4
                                        enum = FastEnum16Qam_Real(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 6
                                        enum = FastEnum64Qam_Real(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                    otherwise
                                        disp('Unexpected Constellation Index');
                                end
                                s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                if i~=M
                                    s_zf0(i+1:M,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:M,j),1,Power_Mc_real);
                                end
                                c((j-1)*mvalue+1:j*mvalue) = enum;
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=M
                                Pre_PED0=Pre_PED0+UU(i,i+1:M)*s_zf0(i+1:M,:);
                            end
                            s_zf1=s_zf0;
                            mmul = mmul * mvalue;
                            REP_PED=Pre_PED0(1:mmul);
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),mmul/length(c)),1,[]);
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD_real=s_SD_ori(reorder);     %resort
                        s_SD_0=reshape(s_SD_real,[],2);
                        s_SD=s_SD_0(:,1)+s_SD_0(:,2)*sqrt(-1);          
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])'; 
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);        
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-go');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,SSFE,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            legendstr = strcat('RSSFE',' m=[',app.m,']');
            app.plot_legend = {app.plot_legend{1:end},legendstr{1}};
        end
        
        function BSSEFE(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));
            
            %% simulation body
            mul = 1;
            mstr = app.m{1};
            dotind = find(mstr==',');
            dig = ones(1,length(dotind)+1);
            strind = 1;
            for i=1:length(dig)-1
                dig(i) = str2double(mstr(strind:dotind(i)-1));
                strind = dotind(i)+1;
            end
            dig(length(dig)) = str2double(mstr(strind:end));            
            for muli = 1:length(dig)
                mul = mul * dig(muli);
            end
            Number_br = mul;
            w=0;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length); 
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));      
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);     
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
                    cols_left = 1:app.mt;
                    mimo_ch_iter = H;
                    mimo_ch_index=zeros(1,app.mt);
                    record_tmp = zeros(app.nr,app.mt);
                    for kk_ord = app.mt:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        record_tmp(:,kk_ord) = inv_norms;
                        [~,~,cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
            %             if kk_ord > (mt-Fs)
            %                 [~, min_index] = max(inv_norms);
            %             else
                            [~, min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(app.nr, app.mt);
                        temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~, U]=qr(H4);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(app.mt,app.mt);
                    UUU=zeros(1,app.mt);
                    for i=1:app.mt
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:app.mt)=U(i,i+1:app.mt)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length       
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % SSFE
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf(order);
                        sd=zeros(app.mt,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(app.mt,Number_br);  %operate one
                        s_zf1=zeros(app.mt,Number_br);  %store one
                        Pre_PED1=zeros(app.mt,Number_br); %store one, record the PED, rather than APED
                        % Partial search APEDs
                        ii=0;
                        mmul = 1;
                        for i=app.mt:-1:1
                            ii=ii+1;
                            mvalue = dig(ii);
                            Power_Mc_real = mvalue;
                            x_hat_tmp = x_hat(i)-UU(i,i+1:app.mt)*s_zf1(i+1:app.mt,:);
                            c = zeros(1,mmul*mvalue);
                            for j=1:mmul
                                switch(app.index)
                                    case 2
                                        enum = BoundedFastExtendedEnum4Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 4
                                        enum = BoundedFastExtendedEnum16Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 6
                                        enum = BoundedFastExtendedEnum64Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                    otherwise
                                        disp('Unexpected Constellation Index');
                                end
                                s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                if i~=app.mt
                                    s_zf0(i+1:app.mt,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:app.mt,j),1,Power_Mc_real);
                                end
                                c((j-1)*mvalue+1:j*mvalue) = enum;
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=app.mt
                                Pre_PED0=Pre_PED0+UU(i,i+1:app.mt)*s_zf0(i+1:app.mt,:);
                            end
                            s_zf1=s_zf0;
                            mmul = mmul * mvalue;
                            REP_PED=Pre_PED0(1:mmul);
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),mmul/length(c)),1,[]);
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori(reorder);     %resort            
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])'; 
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);        
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end            
            %% Plot BER
            [strtmp,~] = sprintf('%d',dig(1));
            str = strtmp;
            for i=2:length(dig)
                [strtmp,~] = sprintf('%d',dig(i));
                str = strcat(str,',',strtmp);
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-gd');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,SSFE,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            legendstr = strcat('BSS-EFE',' m=[',app.m,']');
            app.plot_legend = {app.plot_legend{1:end},legendstr{1}};
        end
        
        
        function FTS(app)
            
            clc;
            
            mapping = mapping_func(app);
            Mc=log2(length(mapping));      
            Power_Mc=2^(Mc);
        
            %% simulation body
            w=0;
            c=mapping;
            Number_br=1;
            [c_nb,c_nbind] = get_singlelayer_nb(app,c);
            TS_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_TS(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
                tic
                w=w+1;
        
                for frame_No=1:app.frame_number(w)   %length of symbols
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_TS=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%
                    %Modulation
                    %%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',Mc/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));
                    %%%%%%%%%%%%%%%%%%%%%%%
                    %Geneate Channel
                    %%%%%%%%%%%%%%%%%%%%%%%
                    H = app.H_corr(:,:,mod(frame_No,app.len)+1);
            %         load H.mat;
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    %trans symbols add noise
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
            %         load noise;
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);
                    %%%%%%%%%%%%%%%%%%%%%%
                    %detection
                    %%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H+eye(app.mt,app.mt)*10^(-SNR/10))*H'*s;      %ZF equalization
        
                    %%%%%%%%%%%%%%%%%%%%%%
                    % SQRD
                    %%%%%%%%%%%%%%%%%%%%%%
                    M=app.mt;
                    cols_left = 1:M;
                    mimo_ch_iter = H;
                    mimo_ch_index=zeros(1,M);
                    record_tmp = zeros(M,M);
                    for kk_ord = M:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        record_tmp(:,kk_ord) = inv_norms;
                        [~, ~, cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
                        % Require to modify for MIMO 88
            %             if kk_ord > (M-Fs)
            %                 [min_val min_index] = max(inv_norms);
            %             else
                            [~, min_index] = min(inv_norms);
            %             end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(M, M);
                        temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H_tmp = [H;eye(app.mt,app.mt)*10^(-SNR/10)];
                    H4 = H_tmp(:,mimo_ch_index);
                    [~,U]=qr(H4);
                    U = U(1:app.mt,1:app.mt);
                    UU=zeros(M,M);
                    UUU=zeros(1,M);
                    for i=1:M
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
        
                    for frame=1:app.frame_length
        
                        % ZF-SIC
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        %Modefy channel matrix
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf(order);
                        sd=zeros(M,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(M,Number_br);  %operate one
                        s_zf1=zeros(M,Number_br);  %store one
        %                 Pre_PED0=zeros(1,Number_br); %operate one
                        Pre_PED1=zeros(M,Number_br); %store one, record the PED, rather than APED
        %                 Pre_Quanti=zeros(1,Number_br); % Record data before quanti
                        %Full search APEDs
                        ii=0;
                        mmul = 1;
            %             for i=M:-1:(M-Fs+1)
                        for i=M:-1:1
                            ii=ii+1;
                            mvalue = 1;
                            Power_Mc_real = mvalue;
                                x_hat_tmp = x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                            cc = zeros(1,mmul*mvalue);
                            for j=1:mmul                                
                                switch(app.index)
                                    case 2
                                        enum = FastEnum4Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 4
                                        enum = FastEnum16Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));
                                    case 6
                                        enum = FastEnum64Qam_Complex(app,x_hat_tmp(j),mvalue,length(x_hat_tmp(j)));       
                                    otherwise
                                        disp('Unexpected Constellation Index');
                                end
                                s_zf0(i,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=enum(1:mvalue)-x_hat(i);
                                if i~=M
                                    s_zf0(i+1:M,Power_Mc_real*(j-1)+1:Power_Mc_real*j)=repmat(s_zf1(i+1:M,j),1,Power_Mc_real);
                                end
                                cc((j-1)*mvalue+1:j*mvalue) = enum;
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=M
                                Pre_PED0=Pre_PED0+UU(i,i+1:M)*s_zf0(i+1:M,:);
                            end
                            s_zf1=s_zf0;
                            mmul = mmul * mvalue;
                            REP_PED=Pre_PED0(1:mmul);
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(cc,Number_br/length(REP_PED),mmul/length(cc)),1,[]);
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori(reorder);     %resort
        
                        % Tabu Search
                        s_v = s(:,frame);
                        %[TS_v,~,~,~,~,~]...
                        %= tabu_search_det_fixed(app,H,s_SD,s_v,c,c_nb,Power_Mc);
                        
                        P_init_1 = app.P_init;
                        Nb_1 = app.Nb;
                        mt_1 = app.mt;
                        rep_max_1 = app.rep_max;
                        iter_max_1 = app.iter_max;
                        beta_1 = app.beta;
                        [TS_v,tm,l_rep,num_rep,tabugm,tabumlcost]...
                        = tabu_search_det_fixed(app,H,s_SD,s_v,c,c_nb,...
                        c_nbind,P_init_1,Nb_1,mt_1,Power_Mc,rep_max_1,iter_max_1,beta_1);
                        s_TS_11=QAM_Demod(app,transpose(TS_v),app.index/2);
                        Z_TS(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_TS_11,app.index/2),app.index,[])';
                    end
                    TS_error(w) = sum(sum(abs(Encoded_Source - Z_TS))) + TS_error(w);
                    
                end
                BER_TS(w)=TS_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
                
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_TS,'-m>');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_TS];
            app.plot_legend = {app.plot_legend{1:end},strcat('FTS',' iter=',num2str(app.iter_max),...
                ' rep=',num2str(app.rep_max),' Nb=',num2str(app.Nb),' P=',num2str(app.P_init),' beta=',num2str(app.beta))};
        end
        
        function FSD(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));                       
            Power_Mc=2^Mc; %Constellation size
            Fs=ceil(sqrt(app.mt)-1); %Required full search levels
            Number_br=2^(Mc*Fs);%Parallel branches
            
            
            %% simulation body
            w=0;
            c=mapping;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;    
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert        
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));      
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    cols_left = 1:app.mt;
                    mimo_ch_iter = H;
                    mimo_ch_index=zeros(1,app.mt);
                    for kk_ord = app.mt:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        [~,~,cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
                        if kk_ord > (app.mt-Fs)
                            [~,min_index] = max(inv_norms);
                        else
                            [~,min_index] = min(inv_norms);
                        end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(app.nr, app.mt);
                        temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~,U]=qr(H4);            
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(app.mt,app.mt);
                    UUU=zeros(1,app.mt);
                    for i=1:app.mt
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:app.mt)=U(i,i+1:app.mt)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length            
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        %FSD
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf(order);
                        sd=zeros(app.mt,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(app.mt,Number_br);  %operate one
                        s_zf1=zeros(app.mt,Number_br);  %store one
                        Pre_PED1=zeros(app.mt,Number_br); %store one, record the PED, rather than APED
                        % Full search APEDs
                        ii=0;
                        for i=app.mt:-1:(app.mt-Fs+1)
                            ii=ii+1;
                            for j=1:2^(Mc*(ii-1))
                                s_zf0(i,Power_Mc*(j-1)+1:Power_Mc*j)=c-x_hat(i);
                                if i~=app.mt
                                    s_zf0(i+1:app.mt,Power_Mc*(j-1)+1:Power_Mc*j)=s_zf1(i+1:app.mt,j);
                                end
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=app.mt
                                Pre_PED0=Pre_PED0+UU(i,i+1:app.mt)*s_zf0(i+1:app.mt,:);
                            end
                            s_zf1=s_zf0;
                            REP_PED=Pre_PED0(1:2^(Mc*ii));
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),2^(Mc*(ii-1))),1,[]);
                        end   
                        % Single search APEDs
                        for i=(app.mt-Fs):-1:1
                            Pre_Quanti=x_hat(i)-UU(i,i+1:app.mt)*s_zf1(i+1:app.mt,:);
                            %Quantification/Slicing  
                            Si1=QAM_Slicer(app,Pre_Quanti,Mc/2,'renorm');
                            %calculate s-zf
                            s_zf1(i,:)=Si1-x_hat(i);
                            %calculate pre_PED
                            Pre_PED1(i,:)=Si1-Pre_Quanti;
                            sd(i,:)=Si1;
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori(reorder);     %resort
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-rx');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,FSD,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            app.plot_legend = {app.plot_legend{1:end},'FSD'};
            
        end
        
        function RFSD(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));                       
            % Power_Mc=2^Mc; %Constellation size
            % Fs=ceil(sqrt(mt)-1); %Required full search levels
            % Number_br=2^(Mc*Fs);%Parallel branches
            M=2*app.mt;
            Fs=ceil(sqrt(M)-1); %Required full search levels
            Number_br=2^(Mc*Fs/2);%Parallel branches
            Power_Mc=2^(Mc/2);
            
            
            %% simulation body
            w=0;
            c=mapping;
            c = unique(real(c));
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;    
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert        
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    base = 2.^(app.index-1:-1:0);
                    Base=repmat(base,app.mt,app.frame_length);
                    Dec=Base.*Encoded_Source;
                    Dec1=sum(reshape(reshape(Dec',1,[]),app.index,[]));
                    helpmap=(0:2^app.index:(app.frame_length*app.mt-1)*2^app.index);
                    Dec2=Dec1+helpmap+1;
                    Map=repmat(mapping,1,app.frame_length*app.mt);
                    Dec3=Map(Dec2);
                    Normalized_Modulated_Source=transpose(reshape(Dec3,app.frame_length,[]));        
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %         h_r=randn(nr,mt)/sqrt(2);
            %         h_j=randn(nr,mt)/sqrt(2);
            %         H=h_r+sqrt(-1)*h_j;
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Real dimension converting
                    H_r=real(H);
                    H_i=imag(H);
                    H_cat_0=cat(2, H_r, -H_i);
                    H_cat_1=cat(2, H_i, H_r);
                    H_Real=cat(1, H_cat_0, H_cat_1);
                    
                    cols_left = 1:M;
                    mimo_ch_iter = H_Real;
                    mimo_ch_index=zeros(1,M);
                    for kk_ord = M:-1:1
                        mimo_ch_inv = pinv(mimo_ch_iter);
                        inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
                        [~,~,cols_index] = find(cols_left);
                        inv_norms = inv_norms(cols_index);
                        if kk_ord > (M-Fs)
                            [~,min_index] = max(inv_norms);
                        else
                            [~,min_index] = min(inv_norms);
                        end            
                        cols_left(cols_index(min_index)) = 0;
                        mimo_ch_index(kk_ord) = cols_index(min_index);            
                        temp_ch = zeros(M, M);
                        temp_ch(:,cols_index(min_index)) = H_Real(:,cols_index(min_index));            
                        mimo_ch_iter = mimo_ch_iter - temp_ch;
                    end        
                    order=mimo_ch_index;
                    [~,reorder]=sort(order);
                    H4 = H_Real(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    [~,U]=qr(H4);            
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(M,M);
                    UUU=zeros(1,M);
                    for i=1:M
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:M)=U(i,i+1:M)./U(i,i);
                        UUU(i)=UU(i,i);
                    end        
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length            
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        %FSD
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        zf_real=cat(1,real(zf),imag(zf));
                        x_hat=zf_real(order);
                        sd=zeros(M,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(M,Number_br);  %operate one
                        s_zf1=zeros(M,Number_br);  %store one
                        Pre_PED1=zeros(M,Number_br); %store one, record the PED, rather than APED
                        % Full search APEDs
                        ii=0;
                        for i=M:-1:(M-Fs+1)
                            ii=ii+1;
                            for j=1:2^(Mc*(ii-1)/2)
                                s_zf0(i,Power_Mc*(j-1)+1:Power_Mc*j)=c-x_hat(i);
                                if i~=M
                                    s_zf0(i+1:M,Power_Mc*(j-1)+1:Power_Mc*j)=s_zf1(i+1:M,j);
                                end
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=M
                                Pre_PED0=Pre_PED0+UU(i,i+1:M)*s_zf0(i+1:M,:);
                            end
                            s_zf1=s_zf0;
                            REP_PED=Pre_PED0(1:2^(Mc*ii/2));
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),2^(Mc*(ii-1)/2)),1,[]);
                        end   
                        % Single search APEDs
                        for i=(M-Fs):-1:1
                            Pre_Quanti=x_hat(i)-UU(i,i+1:M)*s_zf1(i+1:M,:);
                            %Quantification/Slicing  
                            Si1=QAM_Slicer_Real(app,Pre_Quanti,Mc/2,'renorm');
                            %calculate s-zf
                            s_zf1(i,:)=Si1-x_hat(i);
                            %calculate pre_PED
                            Pre_PED1(i,:)=Si1-Pre_Quanti;
                            sd(i,:)=Si1;
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD_real=s_SD_ori(reorder);     %resort
                        s_SD_0=reshape(s_SD_real,[],2);
                        s_SD=s_SD_0(:,1)+s_SD_0(:,2)*sqrt(-1);
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-r+');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,FSD,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            app.plot_legend = {app.plot_legend{1:end},'RFSD'};
        end
        
        function GSD(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
%             Mc=log2(length(mapping));               
            
            %% simulation body
            w=0;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;    
                for frame_No=1:app.frame_number(w)   %length of symbols
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % generate Source Bits
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert        
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Modulation
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    base = 2.^(app.index-1:-1:0);
                    Base=repmat(base,app.mt,app.frame_length);
                    Dec=Base.*Encoded_Source;
                    Dec1=sum(reshape(reshape(Dec',1,[]),app.index,[]));
                    helpmap=(0:2^app.index:(app.frame_length*app.mt-1)*2^app.index);
                    Dec2=Dec1+helpmap+1;
                    Map=repmat(mapping,1,app.frame_length*app.mt);
                    Dec3=Map(Dec2);
                    Normalized_Modulated_Source=transpose(reshape(Dec3,app.frame_length,[]));        
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %         h_r=randn(nr,mt)/sqrt(2);
            %         h_j=randn(nr,mt)/sqrt(2);
            %         H=h_r+sqrt(-1)*h_j;
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Transmit Symbols With Noise
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if size(H, 1) < size(H, 2)
                        H = [H; zeros(size(H, 2) - size(H, 1), size(H, 2))];
                    end
                    [Q, R] = qr(H, 0);
                    z = Q'*s;
                    n = size(H,2);
                    for frame=1:app.frame_length            
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        %GSD
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        s_SD = sphdec(app,z, R, mapping, n, app.Sphere);
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
            toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-kp');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,FSD,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            app.plot_legend = {app.plot_legend{1:end},'GSD'};
            
        end
        
        function ML(app)
            
            clc;
            
            %% simulation setup
            mapping = mapping_func(app);
            Mc=log2(length(mapping));       
            Power_Mc=2^Mc; %Constellation size    
            Fs=app.mt; %Required full search levels
            Number_br=2^(Mc*Fs);%Parallel branches
            %% simulation body
            w=0;
            c=mapping;
            SD_error(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;
            BER_SD(1:length(app.Min_SNR:app.snrstep:app.Max_SNR))=0;            
            for SNR =app.Min_SNR:app.snrstep:app.Max_SNR
            tic
                w=w+1;
                for frame_No=1:app.frame_number(w)   %length of symbols
                    bit_length = app.frame_length * app.index;  %length of bits
                    Source = rand(1,app.mt * bit_length) > 0.5;
                    Encoded_Source=reshape(Source,app.mt,[]); %series to parallel convert
                    Z_SD=zeros(app.mt,bit_length);
                    %%%%%%%%%%%%%%
                    %Modulation
                    %%%%%%%%%%%%%%
                    Symbols = BitToSymbolStream(app,reshape(Encoded_Source',app.mt * bit_length,[])',app.index/2);
                    Normalized_Modulated_Source = QAM_Mod(app,Symbols,Mc/2).';
                    Normalized_Modulated_Source=transpose(reshape(Normalized_Modulated_Source,app.frame_length,[]));    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Geneate Channel Model
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    H=app.H_corr(:,:,mod(frame_No,app.len)+1); 
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    %trans symbols add noise
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    S=H*Normalized_Modulated_Source;
                    noise = (randn(app.nr,app.frame_length) + sqrt(-1)*randn(app.nr,app.frame_length))/sqrt(2);
                    s=S+10^(-SNR/20)*noise*sqrt(app.mt);                 
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Sorting of Channel Matrix
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     cols_left = 1:app.mt;
%                     mimo_ch_iter = H;
%                     mimo_ch_index=zeros(1,app.mt);
%                     for kk_ord = app.mt:-1:1
%                         mimo_ch_inv = pinv(mimo_ch_iter);
%                         inv_norms = diag(mimo_ch_inv*mimo_ch_inv');
%                         [~, ~, cols_index] = find(cols_left);
%                         inv_norms = inv_norms(cols_index);
%                         % Require to modify for MIMO 88
%                         if kk_ord > (app.mt-Fs)
%                             [~,min_index] = max(inv_norms);
%                         else
%                             [~,min_index] = min(inv_norms);
%                         end                        
%                         cols_left(cols_index(min_index)) = 0;
%                         mimo_ch_index(kk_ord) = cols_index(min_index);                        
%                         temp_ch = zeros(app.nr, app.mt);
%                         temp_ch(:,cols_index(min_index)) = H(:,cols_index(min_index));                        
%                         mimo_ch_iter = mimo_ch_iter - temp_ch;
%                     end                    
%                     order=mimo_ch_index;
%                     H4 = H(:,mimo_ch_index);
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % QRD
                    %%%%%%%%%%%%%%%%%%%%%%%%                   
                    [~,U]=qr(H);                
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    % Preprocessing with SQRD
                    %%%%%%%%%%%%%%%%%%%%%%%%
                    UU=zeros(app.mt,app.mt);
                    UUU=zeros(1,app.mt);
                    for i=1:app.mt
                        UU(i,i)=U(i,i)^2;
                        UU(i,i+1:app.mt)=U(i,i+1:app.mt)./U(i,i);
                        UUU(i)=UU(i,i);
                    end                    
                    %%%%%%%%%%%%%%%%%%%%%%
                    % ZF
                    %%%%%%%%%%%%%%%%%%%%%%
                    r=pinv(H'*H)*H'*s;      %ZF
                    for frame=1:app.frame_length        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        % ML
                        %%%%%%%%%%%%%%%%%%%%%%%%%%
                        zf=r(:,frame);
                        x_hat=zf;
                        sd=zeros(app.mt,Number_br);     %record all possible SD symbols
                        s_zf0=zeros(app.mt,Number_br);  %operate one
                        s_zf1=zeros(app.mt,Number_br);  %store one
                        Pre_PED1=zeros(app.mt,Number_br); %store one, record the PED, rather than APED
                        %Full search APEDs
                        ii=0;
                        for i=app.mt:-1:(app.mt-Fs+1)
                            ii=ii+1;
                            for j=1:2^(Mc*(ii-1))
                                s_zf0(i,Power_Mc*(j-1)+1:Power_Mc*j)=c-x_hat(i);
                                if i~=app.mt
                                    for iii=i+1:app.mt
                                        s_zf0(iii,Power_Mc*(j-1)+1:Power_Mc*j)=s_zf1(iii,j);
                                    end
                                end
                            end
                            Pre_PED0=s_zf0(i,:);
                            if i~=app.mt
                                Pre_PED0=Pre_PED0+UU(i,i+1:app.mt)*s_zf0(i+1:app.mt,:);
                            end
                            s_zf1=s_zf0;
                            REP_PED=Pre_PED0(1:2^(Mc*ii));
                            Pre_PED1(i,:)=reshape(repmat(REP_PED,Number_br/length(REP_PED),1),1,[]);
                            sd(i,:)=reshape(repmat(c,Number_br/length(REP_PED),2^(Mc*(ii-1))),1,[]);
                        end   
                        %Single search APEDs
                        for i=(app.mt-Fs):-1:1
                            Pre_Quanti=x_hat(i)-UU(i,i+1:app.mt)*s_zf1(i+1:app.mt,:);
                            %Quantification/Slicing
                            Si1=QAM_Slicer(app,Pre_Quanti,Mc/2,'renorm');
                            %calculate s-zf
                            s_zf1(i,:)=Si1-x_hat(i);
                            %calculate pre_PED
                            Pre_PED1(i,:)=Si1-Pre_Quanti;
                            sd(i,:)=Si1;
                        end
                        PED=repmat(UUU,Number_br,1)'.*(abs(Pre_PED1).^2);
                        APED=sum(PED);
                        [~,Index_SD] = min(APED);
                        s_SD_ori=sd(:,Index_SD);
                        s_SD=s_SD_ori;     %resort
                        s_SD_11=QAM_Demod(app,transpose(s_SD),app.index/2);
                        Z_SD(:,(frame-1)*app.index+1:frame*app.index)= reshape(SymbolToBitStream(app,s_SD_11,app.index/2),app.index,[])';
                    end
                    SD_error(w) = sum(sum(abs(Encoded_Source - Z_SD))) + SD_error(w);
                end
                BER_SD(w)=SD_error(w)/(app.mt * app.frame_number(w) * app.frame_length * app.index);
                toc
            end
            SNRplot=app.Min_SNR:app.snrstep:app.Max_SNR;
            hold(app.UIAxes,app.figurehold);
            semilogy (app.UIAxes,SNRplot,BER_SD,'-kh');
            set(app.UIAxes,'yscale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR(dB)');
            ylabel(app.UIAxes,'BER (Bit Error Rate) ');
            ylim(app.UIAxes,[1e-5 1]);
%             str = strcat('Complex,FSD,',num2str(app.mt),'x',num2str(app.nr),',',num2str(2^app.index),'QAM');
%             title(app.UIAxes,str);
            app.plot_x = [app.plot_x;SNRplot];
            app.plot_y = [app.plot_y;BER_SD];
            app.plot_legend = {app.plot_legend{1:end},'ML'};
        end
        
        function appmain(app,event)
                       
            %% generate channel model
            app.len = 4096;
            channeltype = app.Channel;
            switch(channeltype)
                case 'Uncorrelated'
                    app.H_corr=RAND_CH(app);
                case 'Correlated'
                    app.H_corr=LTE_TU_CH(app);
                otherwise
            end
            
            if strcmp(app.Case,'MF')
                %% MF
                app.frame_number =[100 100 100 100 200 200 200 400 500 500 1000]*50*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                MF(app);      
            elseif strcmp(app.Case,'ZF')
                %% ZF
                app.frame_number =[100 100 100 100 200 200 200 400 500 500 1000]*50*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                ZF(app);
            elseif strcmp(app.Case,'MMSE')
                %% MMSE
                app.frame_number =[100 100 100 100 200 200 200 400 500 700 1200]*50*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                MMSE(app);
            elseif strcmp(app.Case,'KBEST')
                %% K-BEST
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % K = 2;
                KBEST(app);
            elseif strcmp(app.Case,'RKBEST')
                %% K-BEST
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % K = 2;
                RKBEST(app);
            elseif strcmp(app.Case,'SSFE')
                %% SSFE
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % m = [4 1];
                SSFE(app);
            elseif strcmp(app.Case,'RSSFE')
                %% SSFE
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % m = [4 1];
                RSSFE(app);
            elseif strcmp(app.Case,'BSSEFE')
                %% SSFE
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % m = [4 1];
                BSSEFE(app);
            elseif strcmp(app.Case,'FTS')
                %% SSFE
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*10*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                % m = [4 1];
                FTS(app);
            elseif strcmp(app.Case,'FSD')
                %% FSD
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                FSD(app);
            elseif strcmp(app.Case,'RFSD')
                %% FSD
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                RFSD(app);
            elseif strcmp(app.Case,'GSD')
                %% FSD
                app.frame_number =[100 100 100 100 200 200 500 1000 1500 2000 2500]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                GSD(app);
            elseif strcmp(app.Case,'ML')
                %% FSD
                app.frame_number =[100 100 100 100 200 500 1000 1500 2000 2500 3000]*80*app.samplescale; % frame number per SNR
                app.frame_length = 1; % number of symbols per frame
                ML(app);
            end
            
        end
        
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                appmain(app);
            end
        end

        % Value changed function: CaseDropDown
        function CaseDropDownValueChanged(app, event)
        
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                app.Case = app.CaseDropDown.Value;
                if strcmp(app.Case,'ZF') || strcmp(app.Case,'MMSE')
                    app.SICCheckBox.Visible = 'on';
                else
                    app.SICCheckBox.Visible = 'off';
                end
                if strcmp(app.Case,'KBEST') || strcmp(app.Case,'RKBEST')
                    app.KEditField.Visible = 'on';
                    app.KEditFieldLabel.Visible = 'on';
                else
                    app.KEditField.Visible = 'off';
                    app.KEditFieldLabel.Visible = 'off';
                end
                if strcmp(app.Case,'SSFE') || strcmp(app.Case,'RSSFE') || strcmp(app.Case,'BSSEFE')
                    app.mTextArea.Visible = 'on';
                    app.mTextAreaLabel.Visible = 'on';
                else
                    app.mTextArea.Visible = 'off';
                    app.mTextAreaLabel.Visible = 'off';
                end
                if strcmp(app.Case,'FTS')
                    app.ReactiveEditField.Visible = 'on';
                    app.ReactiveEditFieldLabel.Visible = 'on';
                    app.IterationEditField.Visible = 'on';
                    app.IterationEditFieldLabel.Visible = 'on';
                    app.RepeatEditField.Visible = 'on';
                    app.RepeatEditFieldLabel.Visible = 'on';
                    app.NeighbourEditField.Visible = 'on';
                    app.NeighbourEditFieldLabel.Visible = 'on';
                    app.TabuPeriodEditField.Visible = 'on';
                    app.TabuPeriodEditFieldLabel.Visible = 'on';
                else
                    app.ReactiveEditField.Visible = 'off';
                    app.ReactiveEditFieldLabel.Visible = 'off';
                    app.IterationEditField.Visible = 'off';
                    app.IterationEditFieldLabel.Visible = 'off';
                    app.RepeatEditField.Visible = 'off';
                    app.RepeatEditFieldLabel.Visible = 'off';
                    app.NeighbourEditField.Visible = 'off';
                    app.NeighbourEditFieldLabel.Visible = 'off';
                    app.TabuPeriodEditField.Visible = 'off';
                    app.TabuPeriodEditFieldLabel.Visible = 'off';
                end
                if strcmp(app.Case,'GSD')
                    app.SphereEditField.Visible = 'on';
                    app.SphereEditFieldLabel.Visible = 'on';
                else
                    app.SphereEditField.Visible = 'off';
                    app.SphereEditFieldLabel.Visible = 'off';
                end
            end
            
        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                value = app.ModulationDropDown.Value;
                if strcmp(value,'4QAM')
                    app.index = 2;
                elseif strcmp(value,'16QAM')
                    app.index = 4;
                elseif strcmp(value,'64QAM')
                    app.index = 6;
                end
            end
            
        end

        % Value changed function: mTextArea
        function mTextAreaValueChanged(app, event)
            
            app.m = app.mTextArea.Value; 
            
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            
            app.K = app.KEditField.Value;
            
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            
            app.Channel = app.ChannelDropDown.Value; 
                
        end

        % Value changed function: minSNREditField
        function minSNREditFieldValueChanged(app, event)
            
            app.Min_SNR = app.minSNREditField.Value;
            
        end

        % Value changed function: maxSNREditField
        function maxSNREditFieldValueChanged(app, event)
            
            app.Max_SNR = app.maxSNREditField.Value;
            
        end

        % Value changed function: TXEditField
        function TXEditFieldValueChanged(app, event)
            
            app.mt = app.TXEditField.Value;
            
        end

        % Value changed function: RXEditField
        function RXEditFieldValueChanged(app, event)
            
            app.nr = app.RXEditField.Value;
            
        end

        % Value changed function: Corr_typeDropDown
        function Corr_typeDropDownValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                value = app.Corr_typeDropDown.Value;
                if strcmp(value,'Low')
                    app.Corr_ind = 0;
                elseif strcmp(value,'Medium')
                    app.Corr_ind = 1;
                elseif strcmp(value,'High')
                    app.Corr_ind = 2;
                end
            end
        end

        % Value changed function: HoldFigureCheckBox
        function HoldFigureCheckBoxValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                value = app.HoldFigureCheckBox.Value;
                if value == 1
                    app.figurehold = 'on';
                else
                    app.figurehold = 'off';
                end
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                if app.SaveEn == true
                    fh = figure;
                    semilogy(app.plot_x.',app.plot_y.');
                    grid on;
                    ylabel('Bit Error Rate');
                    xlabel('SNR (dB)');
                    legend(app.plot_legend);
                    filename = strcat(app.Figurename,'.fig');
                    saveas(fh, filename,'fig');
                    close(fh);
                else                
                    app.PasswordEditField.Value = 'Input password';
                end
            end
            
        end

        % Value changed function: FigureNameEditField
        function FigureNameEditFieldValueChanged(app, event)
            app.Figurename = app.FigureNameEditField.Value;            
        end

        % Value changed function: SNRstepEditField
        function SNRstepEditFieldValueChanged(app, event)
            app.snrstep = app.SNRstepEditField.Value;            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                cla(app.UIAxes);
                title(app.UIAxes,'');
                grid(app.UIAxes,'off');
                close all;
                clc;
                clear;
                app.plot_x = [];
                app.plot_y = [];
                app.plot_legend = {};
            end
            
        end

        % Value changed function: SampleScaleEditField
        function SampleScaleEditFieldValueChanged(app, event)
            
            app.samplescale = app.SampleScaleEditField.Value;
            
        end

        % Button pushed function: LegendButton
        function LegendButtonPushed(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                if ~isempty(app.plot_legend)
                    legend(app.UIAxes,app.plot_legend{:});
                end
            end
            
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                value = app.PasswordEditField.Value;
                if strcmp(value,app.Password)
                    app.SaveEn = true;
                else
                    app.SaveEn = false;
                end
            end
            
        end

        % Value changed function: SphereEditField
        function SphereEditFieldValueChanged(app, event)
            
            app.Sphere = app.SphereEditField.Value;
            if app.Sphere == 0
                app.Sphere = realmax;
            end
            
        end

        % Callback function
        function RealCheckBoxValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                app.Real_Arith = app.RealCheckBox.Value;
            end
            
        end

        % Value changed function: SICCheckBox
        function SICCheckBoxValueChanged(app, event)
            
            c = clock;
            t = fix(c);
            time = t(1)*10000+t(2)*100+t(3);
            if time>app.expired || time<app.started
                txt = 'APP Expired!!!';
                text(app.UIAxes,0.2,0.5,txt,'color','r','fontsize',48);
            else
                app.With_SIC = app.SICCheckBox.Value;
            end
            
        end

        % Value changed function: RepeatEditField
        function RepeatEditFieldValueChanged(app, event)
            
            app.rep_max = app.RepeatEditField.Value;
            
        end

        % Value changed function: IterationEditField
        function IterationEditFieldValueChanged(app, event)
            
            app.iter_max = app.IterationEditField.Value;
            
        end

        % Value changed function: NeighbourEditField
        function NeighbourEditFieldValueChanged(app, event)
            
            app.Nb = app.NeighbourEditField.Value;
            
        end

        % Value changed function: TabuPeriodEditField
        function TabuPeriodEditFieldValueChanged(app, event)
            
            app.P_init = app.TabuPeriodEditField.Value;
            
        end

        % Value changed function: ReactiveEditField
        function ReactiveEditFieldValueChanged(app, event)
            
            app.beta = app.ReactiveEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MIMOUIFigure = uifigure;
            app.MIMOUIFigure.Name = 'MIMO';
            app.UIAxes = uiaxes(app.MIMOUIFigure);
            xlabel(app.UIAxes, 'SNR (dB)')
            ylabel(app.UIAxes, 'Bit Error Rate')
            app.TabGroup = uitabgroup(app.MIMOUIFigure);
            app.ApplicationTab = uitab(app.TabGroup);
            app.ApplicationTab.Title = 'Application';
            app.CaseDropDownLabel = uilabel(app.ApplicationTab);
            app.CaseDropDownLabel.Text = 'Case';
            app.CaseDropDown = uidropdown(app.ApplicationTab);
            app.CaseDropDown.Items = {'MF', 'ZF', 'MMSE', 'KBEST', 'RKBEST', 'SSFE', 'RSSFE', 'BSS-EFE', 'FTS', 'FSD', 'RFSD', 'GSD', 'ML'};
            app.CaseDropDown.ItemsData = {'MF', 'ZF', 'MMSE', 'KBEST', 'RKBEST', 'SSFE', 'RSSFE', 'BSSEFE', 'FTS', 'FSD', 'RFSD', 'GSD', 'ML'};
            app.CaseDropDown.ValueChangedFcn = createCallbackFcn(app, @CaseDropDownValueChanged, true);
            app.CaseDropDown.Value = 'MF';
            app.mTextAreaLabel = uilabel(app.ApplicationTab);
            app.mTextAreaLabel.Visible = 'off';
            app.mTextAreaLabel.Text = 'm';
            app.mTextArea = uitextarea(app.ApplicationTab);
            app.mTextArea.ValueChangedFcn = createCallbackFcn(app, @mTextAreaValueChanged, true);
            app.mTextArea.Visible = 'off';
            app.KEditFieldLabel = uilabel(app.ApplicationTab);
            app.KEditFieldLabel.Visible = 'off';
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Visible = 'off';
            app.SphereEditFieldLabel = uilabel(app.ApplicationTab);
            app.SphereEditFieldLabel.Visible = 'off';
            app.SphereEditFieldLabel.Text = 'Sphere';
            app.SphereEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.SphereEditField.ValueChangedFcn = createCallbackFcn(app, @SphereEditFieldValueChanged, true);
            app.SphereEditField.Visible = 'off';
            app.SICCheckBox = uicheckbox(app.ApplicationTab);
            app.SICCheckBox.ValueChangedFcn = createCallbackFcn(app, @SICCheckBoxValueChanged, true);
            app.SICCheckBox.Visible = 'off';
            app.SICCheckBox.Text = '   SIC';
            app.IterationEditFieldLabel = uilabel(app.ApplicationTab);
            app.IterationEditFieldLabel.Visible = 'off';
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.IterationEditField.ValueChangedFcn = createCallbackFcn(app, @IterationEditFieldValueChanged, true);
            app.IterationEditField.Visible = 'off';
            app.RepeatEditFieldLabel = uilabel(app.ApplicationTab);
            app.RepeatEditFieldLabel.Visible = 'off';
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.RepeatEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatEditFieldValueChanged, true);
            app.RepeatEditField.Visible = 'off';
            app.NeighbourEditFieldLabel = uilabel(app.ApplicationTab);
            app.NeighbourEditFieldLabel.Visible = 'off';
            app.NeighbourEditFieldLabel.Text = 'Neighbour';
            app.NeighbourEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.NeighbourEditField.ValueChangedFcn = createCallbackFcn(app, @NeighbourEditFieldValueChanged, true);
            app.NeighbourEditField.Visible = 'off';
            app.TabuPeriodEditFieldLabel = uilabel(app.ApplicationTab);
            app.TabuPeriodEditFieldLabel.Visible = 'off';
            app.TabuPeriodEditFieldLabel.Text = 'Tabu Period';
            app.TabuPeriodEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.TabuPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @TabuPeriodEditFieldValueChanged, true);
            app.TabuPeriodEditField.Visible = 'off';
            app.ReactiveEditFieldLabel = uilabel(app.ApplicationTab);
            app.ReactiveEditFieldLabel.Visible = 'off';
            app.ReactiveEditFieldLabel.Text = 'Reactive';
            app.ReactiveEditField = uieditfield(app.ApplicationTab, 'numeric');
            app.ReactiveEditField.ValueChangedFcn = createCallbackFcn(app, @ReactiveEditFieldValueChanged, true);
            app.ReactiveEditField.Visible = 'off';
            app.DecodingTab = uitab(app.TabGroup);
            app.DecodingTab.Title = 'Decoding';
            app.CodingSchemeDropDownLabel = uilabel(app.DecodingTab);
            app.CodingSchemeDropDownLabel.Text = 'Coding Scheme';
            app.CodingSchemeDropDown = uidropdown(app.DecodingTab);
            app.CodingSchemeDropDown.Items = {'Conv', 'Hamming', 'Turbo', 'BCH', 'LDPC', 'Polar', 'Reed–Solomon'};
            app.CodingSchemeDropDown.Value = 'Conv';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.HoldFigureCheckBox = uicheckbox(app.SimulationTab);
            app.HoldFigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldFigureCheckBoxValueChanged, true);
            app.HoldFigureCheckBox.Text = 'Hold Figure';
            app.LegendButton = uibutton(app.SimulationTab, 'push');
            app.LegendButton.ButtonPushedFcn = createCallbackFcn(app, @LegendButtonPushed, true);
            app.LegendButton.Text = 'Legend';
            app.minSNREditFieldLabel = uilabel(app.SimulationTab);
            app.minSNREditFieldLabel.Text = 'min SNR';
            app.minSNREditField = uieditfield(app.SimulationTab, 'numeric');
            app.minSNREditField.ValueChangedFcn = createCallbackFcn(app, @minSNREditFieldValueChanged, true);
            app.minSNREditField.Value = 6;
            app.maxSNREditFieldLabel = uilabel(app.SimulationTab);
            app.maxSNREditFieldLabel.Text = 'max SNR';
            app.maxSNREditField = uieditfield(app.SimulationTab, 'numeric');
            app.maxSNREditField.ValueChangedFcn = createCallbackFcn(app, @maxSNREditFieldValueChanged, true);
            app.maxSNREditField.Value = 26;
            app.TXEditFieldLabel = uilabel(app.SimulationTab);
            app.TXEditFieldLabel.Text = 'TX';
            app.TXEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TXEditField.ValueChangedFcn = createCallbackFcn(app, @TXEditFieldValueChanged, true);
            app.TXEditField.Value = 2;
            app.RXEditFieldLabel = uilabel(app.SimulationTab);
            app.RXEditFieldLabel.Text = 'RX';
            app.RXEditField = uieditfield(app.SimulationTab, 'numeric');
            app.RXEditField.ValueChangedFcn = createCallbackFcn(app, @RXEditFieldValueChanged, true);
            app.RXEditField.Value = 2;
            app.ModulationDropDownLabel = uilabel(app.SimulationTab);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.SimulationTab);
            app.ModulationDropDown.Items = {'4QAM', '16QAM', '64QAM'};
            app.ModulationDropDown.ItemsData = {'4QAM', '16QAM', '64QAM'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = '4QAM';
            app.ChannelDropDownLabel = uilabel(app.SimulationTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SimulationTab);
            app.ChannelDropDown.Items = {'Uncorrelated', 'Correlated'};
            app.ChannelDropDown.ItemsData = {'Uncorrelated', 'Correlated'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'Uncorrelated';
            app.Corr_typeDropDownLabel = uilabel(app.SimulationTab);
            app.Corr_typeDropDownLabel.Text = 'Corr_type';
            app.Corr_typeDropDown = uidropdown(app.SimulationTab);
            app.Corr_typeDropDown.Items = {'Low', 'Medium', 'High'};
            app.Corr_typeDropDown.ItemsData = {'Low', 'Medium', 'High'};
            app.Corr_typeDropDown.ValueChangedFcn = createCallbackFcn(app, @Corr_typeDropDownValueChanged, true);
            app.Corr_typeDropDown.Value = 'Low';
            app.SNRstepEditFieldLabel = uilabel(app.SimulationTab);
            app.SNRstepEditFieldLabel.Text = 'SNR step';
            app.SNRstepEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SNRstepEditField.ValueChangedFcn = createCallbackFcn(app, @SNRstepEditFieldValueChanged, true);
            app.SNRstepEditField.Value = 2;
            app.SampleScaleEditFieldLabel = uilabel(app.SimulationTab);
            app.SampleScaleEditFieldLabel.Text = 'Sample Scale';
            app.SampleScaleEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SampleScaleEditField.ValueChangedFcn = createCallbackFcn(app, @SampleScaleEditFieldValueChanged, true);
            app.SampleScaleEditField.Value = 1;
            app.RunButton = uibutton(app.SimulationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.FigureNameEditFieldLabel = uilabel(app.FileTab);
            app.FigureNameEditFieldLabel.Text = 'Figure Name';
            app.FigureNameEditField = uieditfield(app.FileTab, 'text');
            app.FigureNameEditField.ValueChangedFcn = createCallbackFcn(app, @FigureNameEditFieldValueChanged, true);
            app.FigureNameEditField.Value = 'MIMO_Detector_';
            app.PasswordEditFieldLabel = uilabel(app.FileTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.FileTab, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.FileTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MIMODetDemo_v3a

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MIMOUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MIMOUIFigure)
        end
    end
end